classdef PinvMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GetReconstructionsPanel      matlab.ui.container.Panel
        ClearAllButton               matlab.ui.control.Button
        Switch                       matlab.ui.control.Switch
        SwitchLabel                  matlab.ui.control.Label
        ExportResultsButton          matlab.ui.control.Button
        StartPinvReconButton         matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        LoadDataPanel                matlab.ui.container.Panel
        ClearButton_2                matlab.ui.control.Button
        LoadSuccessfulCheckBox_2     matlab.ui.control.CheckBox
        orLabel_2                    matlab.ui.control.Label
        GeneratePhantomButton        matlab.ui.control.Button
        ImportMRdataButton           matlab.ui.control.Button
        ChooseEncodeMechanismsPanel  matlab.ui.container.Panel
        TranslationsCheckBox         matlab.ui.control.CheckBox
        ChemicalShiftCheckBox        matlab.ui.control.CheckBox
        OffResonanceB0CheckBox       matlab.ui.control.CheckBox
        CoilSensitivityCheckBox      matlab.ui.control.CheckBox
        GradientCheckBox             matlab.ui.control.CheckBox
        LoadTrajecotryPanel          matlab.ui.container.Panel
        ClearButton                  matlab.ui.control.Button
        orLabel                      matlab.ui.control.Label
        GenerateaTrajectoryButton    matlab.ui.control.Button
        LoadSuccessfulCheckBox       matlab.ui.control.CheckBox
        ImportaTrajectoryButton      matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Kx % Description
        Ky
        time
        imaging_phantom
        npx
        
    end
    
    properties (Access = private)
        GenerateTrajectory
        CreatePhantom

        % initialise encode matrices for all effects:
        % set to empty arrays in startup

        gradient_encode
        offCtr_encode
        b0_encode
        gradNonLin_encode
        coilSense_encode
        chemShift_encode
        rot_encode
        spatioTemporal_encode

        % is the data synthetic or imported?
        synthetic_or_imported %0=synthetic, 1=imported

    end
    
    methods (Access = public)
         
        % function update_trajectory(app, Kx, Ky, time)
        % 
        %     app.Kx = Kx;
        %     app.Ky = Ky;
        %     app.time = time;
        
        function update_trajectory(app, kx, ky)
            app.Kx = kx;
            app.Ky = ky;
            plot(app.UIAxes2, app.Kx, app.Ky)
            app.LoadSuccessfulCheckBox.Value = 1;
        end

        function update_phantom(app, P, ntx)
            app.imaging_phantom = P;
            app.npx = ntx;
            app.LoadSuccessfulCheckBox_2.Value = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %add modules to path:
            % addpath(fullfile(fileparts(mfilename('fullpath')), 'modules'));
            % addpath(fullfile(fileparts(mfilename('fullpath')), 'Supporting Scripts'));
            % addpath(fullfile)

            %% Get the full path of the running app file
            appFolder = fileparts(mfilename('fullpath'));
            fprintf('app folder', appFolder)
       
            % Set MATLAB's current folder (optional)
            cd(appFolder);  
            
            % Add the app folder to the path
            addpath(appFolder);
            
            % Optionally add subfolders too
            addpath(genpath(appFolder));

            
            %set all encode matrices to empty array:
            app.gradient_encode = [];
            app.offCtr_encode = [];
            app.b0_encode = [];
            app.gradNonLin_encode = [];
            app.coilSense_encode = [];
            app.chemShift_encode = [];
            app.rot_encode = [];
            app.spatioTemporal_encode = [];

            %Gradient Encode must always be included
            app.GradientCheckBox.Value = 0;
            app.GradientCheckBox.Enable = 'off';
            app.LoadSuccessfulCheckBox.Enable = 0;
            app.LoadSuccessfulCheckBox_2.Enable = 0;
            
            %is data synthetic or imported
            app.synthetic_or_imported = [];

            %Disable clear buttons until there is data to clear
            app.ClearButton.Enable = 'off';
            app.ClearButton_2.Enable = 'off';
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: ImportaTrajectoryButton
        function ImportaTrajectoryButtonPushed(app, event)
            %import must be a .mat file 
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');    
            if isequal(file, 0)
                return; % User canceled
            end
        
            try
                data = load(fullfile(path, file));
                vars = fieldnames(data);
        
                if length(vars) ~= 3
                    errordlg('The file must contain exactly 3 variables, corresponding to Kx, Ky and time.', 'Import Error');
                    return;
                end
        
                vectors = {};
                for i = 1:length(vars)
                    val = data.(vars{i});
                    if isnumeric(val) && isvector(val)
                        vectors{end+1} = val; %#ok<AGROW>
                    else
                        errordlg(['Variable "' vars{i} '" is not a numeric vector.'], 'Import Error');
                        return;
                    end
                end
        
                % Assign to app properties 
                app.Kx = vectors{1};
                app.Ky = vectors{2};
                app.time = vectors{3};
        
                msgbox('Trajectory imported successfully.', 'Import Successful');
        
            catch ME
                errordlg(['Failed to load file: ' ME.message], 'Load Error');
            end
            
            %plot imported trajectory
            plot(app.UIAxes2, app.Kx, app.Ky)
            app.LoadSuccessfulCheckBox.Value = 1;

            %disable/enable button after dialog box is opened
            app.ClearButton.Enable = 'on';
            app.GenerateaTrajectoryButton.Enable = 'off';
            app.ImportaTrajectoryButton.Enable = 'off';

        end

        % Button pushed function: GenerateaTrajectoryButton
        function GenerateaTrajectoryButtonPushed(app, event)
            app.GenerateTrajectory = GenerateTrajectoryApp(app);

            %disable/enable button after dialog box is opened
            app.ClearButton.Enable = 'on';
            app.GenerateaTrajectoryButton.Enable = 'off';
            app.ImportaTrajectoryButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.GenerateTrajectory)
            delete(app.CreatePhantom)
            delete(app)
        end

        % Callback function
        function CoilSensitivityCheckBoxValueChanged(app, event)
            value = app.CoilSensitivityCheckBox.Value;
            if value == 1
                %add option to import coil sensitivity map

            elseif value == 0
                app.coilSense_encode.Value = [];
                
            end
            
        end

        % Value changed function: OffResonanceB0CheckBox
        function OffResonanceB0CheckBoxValueChanged(app, event)
            value = app.OffResonanceB0CheckBox.Value;
            synth_or_imp = app.synthetic_or_imported;
            if synth_or_imp == 0
                if value == 1
                    % get synthetic b0 map    
                    x = linspace(-1,1,app.npx);
                    [~, Y] = ndgrid(x, x);
                    app.b0_encode = 125*Y.^2-30;
                else
                    app.b0_encode.Value = [];
                end

            elseif synth_or_imp == 1
                %add code to import own encode matrix
                % Prompt user to choose import method
                choice = questdlg('Import matrix from workspace or file?', ...
                                  'Import Options', ...
                                  'From Workspace', ...
                                  'From .mat File', ...
                                  'Cancel');

                switch choice
                    case 'From Workspace'
                        % Ask for variable name
                        varName = inputdlg('Enter the name of the variable:', ...
                                           'Import from Workspace');
                        if isempty(varName)
                            return;  % User canceled
                        end
                        try
                            data = evalin('base', varName{1});
                            app.b0_encode = data;  % Replace with your app's property
                            msgbox('Data imported from workspace.', 'Import Successful');
                        catch
                            errordlg('Could not import variable from workspace.', 'Import Error');
                        end
            
                    case 'From .mat File'
                        [file, path] = uigetfile('*.mat', 'Select a MAT-file');
                        if isequal(file, 0)
                            return;  % User canceled
                        end
                        try
                            s = load(fullfile(path, file));
                            varNames = fieldnames(s);
                            if isempty(varNames)
                                errordlg('No variables found in the file.', 'Import Error');
                                return;
                            end
                            % Let user select which variable to import
                            [selectionIndex, ok] = listdlg('PromptString', 'Select a variable to import:', ...
                                                           'SelectionMode', 'single', ...
                                                           'ListString', varNames);
                            if ok
                                data = s.(varNames{selectionIndex});
                                app.b0_encode = data;  % Replace with your app's property
                                msgbox('Data imported from file.', 'Import Successful');
                            end
                        catch
                            errordlg('Failed to load data from .mat file.', 'Import Error');
                        end
            
                    otherwise
                        % Cancel pressed — do nothing            
                end

            else 
                errordlg('Error: No MR data uploaded. Upload this first.', 'Upload Error');
            end

        end

        % Value changed function: ChemicalShiftCheckBox
        function ChemicalShiftCheckBoxValueChanged(app, event)
            value = app.ChemicalShiftCheckBox.Value;
            if value == 1
                %add option to import map

            elseif value == 0
                app.chemShift_encode.Value = [];
                
            end
            
        end

        % Value changed function: TranslationsCheckBox
        function TranslationsCheckBoxValueChanged(app, event)
            value = app.TranslationsCheckBox.Value;
             if value == 1
                %add option to import map

            elseif value == 0
                app.offCtr_encode.Value = [];
                
            end
        end

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            
            %set to synthetic data:
            app.synthetic_or_imported = 0;

            app.CreatePhantom = CreatePhantomApp(app);
            app.ImportMRdataButton.Enable = 'off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.LoadSuccessfulCheckBox.Value = 0;
            app.GenerateaTrajectoryButton.Enable = 'on';
            app.ImportaTrajectoryButton.Enable = 'on';
            cla(app.UIAxes2)
            app.Kx = [];
            app.Ky = [];
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.GeneratePhantomButton.Enable = 'on';
            app.ImportMRdataButton.Enable = 'on';
            app.LoadSuccessfulCheckBox_2.Value = 0;

            app.imaging_phantom = [];
            app.npx = [];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadTrajecotryPanel = uipanel(app.UIFigure);
            app.LoadTrajecotryPanel.Title = '1. Load Trajecotry';
            app.UIAxes2 = uiaxes(app.LoadTrajecotryPanel);
            title(app.UIAxes2, 'k-space trajectory')
            xlabel(app.UIAxes2, 'kx')
            ylabel(app.UIAxes2, 'ky')
            zlabel(app.UIAxes2, 'kz')
            app.ImportaTrajectoryButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.ImportaTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportaTrajectoryButtonPushed, true);
            app.ImportaTrajectoryButton.Text = 'Import a Trajectory';
            app.LoadSuccessfulCheckBox = uicheckbox(app.LoadTrajecotryPanel);
            app.LoadSuccessfulCheckBox.Text = 'Load Successful';
            app.GenerateaTrajectoryButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.GenerateaTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateaTrajectoryButtonPushed, true);
            app.GenerateaTrajectoryButton.Text = 'Generate a Trajectory';
            app.orLabel = uilabel(app.LoadTrajecotryPanel);
            app.orLabel.Text = 'or';
            app.ClearButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ChooseEncodeMechanismsPanel = uipanel(app.UIFigure);
            app.ChooseEncodeMechanismsPanel.Title = '3. Choose Encode Mechanisms';
            app.GradientCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.GradientCheckBox.Text = 'Gradient';
            app.CoilSensitivityCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.CoilSensitivityCheckBox.Text = 'Coil Sensitivity';
            app.OffResonanceB0CheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.OffResonanceB0CheckBox.ValueChangedFcn = createCallbackFcn(app, @OffResonanceB0CheckBoxValueChanged, true);
            app.OffResonanceB0CheckBox.Text = 'Off Resonance (B0)';
            app.ChemicalShiftCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.ChemicalShiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChemicalShiftCheckBoxValueChanged, true);
            app.ChemicalShiftCheckBox.Text = 'Chemical Shift';
            app.TranslationsCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.TranslationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslationsCheckBoxValueChanged, true);
            app.TranslationsCheckBox.Text = 'Translations';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = '2. Load Data';
            app.ImportMRdataButton = uibutton(app.LoadDataPanel, 'push');
            app.ImportMRdataButton.Text = 'Import MR data';
            app.GeneratePhantomButton = uibutton(app.LoadDataPanel, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.orLabel_2 = uilabel(app.LoadDataPanel);
            app.orLabel_2.Text = 'or';
            app.LoadSuccessfulCheckBox_2 = uicheckbox(app.LoadDataPanel);
            app.LoadSuccessfulCheckBox_2.Text = 'Load Successful';
            app.ClearButton_2 = uibutton(app.LoadDataPanel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.GetReconstructionsPanel = uipanel(app.UIFigure);
            app.GetReconstructionsPanel.Title = '4. Get Reconstructions';
            app.UIAxes = uiaxes(app.GetReconstructionsPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartPinvReconButton = uibutton(app.GetReconstructionsPanel, 'push');
            app.StartPinvReconButton.Text = 'Start Pinv-Recon';
            app.ExportResultsButton = uibutton(app.GetReconstructionsPanel, 'push');
            app.ExportResultsButton.Text = 'Export Results';
            app.SwitchLabel = uilabel(app.GetReconstructionsPanel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.GetReconstructionsPanel, 'slider');
            app.ClearAllButton = uibutton(app.GetReconstructionsPanel, 'push');
            app.ClearAllButton.Text = 'Clear All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PinvMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end