classdef PinvReconApp_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ReconMetricsLabel               matlab.ui.control.Label
        PinvReconPanel                  matlab.ui.container.Panel
        ReconCorrectedFig               matlab.ui.control.UIAxes
        ReconFig                        matlab.ui.control.UIAxes
        SRFandNoiseMatricesPanel        matlab.ui.container.Panel
        InputWaveformPanel              matlab.ui.container.Panel
        ResultsLabel                    matlab.ui.control.Label
        MatrixInversionPanel            matlab.ui.container.Panel
        ReconSizeEditField              matlab.ui.control.NumericEditField
        ReconSizeEditFieldLabel         matlab.ui.control.Label
        GetPinvReconButton              matlab.ui.control.Button
        PinvMethodDropDown              matlab.ui.control.DropDown
        PinvMethodDropDownLabel         matlab.ui.control.Label
        ChooseEncodeMechanismsOptionalPanel  matlab.ui.container.Panel
        ClearDataButton_4               matlab.ui.control.Button
        ClearDataButton_3               matlab.ui.control.Button
        ClearDataButton_2               matlab.ui.control.Button
        CurrentGradNonLinMapFileEditField  matlab.ui.control.EditField
        CurrentGradNonLinMapFileEditFieldLabel  matlab.ui.control.Label
        LoadSuccessfulCheckBox_4        matlab.ui.control.CheckBox
        LoadGradNonLinMapButton         matlab.ui.control.Button
        CurrentCoilSensitivityMapFileEditField  matlab.ui.control.EditField
        CurrentCoilSensitivityMapFileEditFieldLabel  matlab.ui.control.Label
        CurrentB0FieldMapFileEditField  matlab.ui.control.EditField
        CurrentB0FieldMapFileEditFieldLabel  matlab.ui.control.Label
        LoadSuccessfulCheckBox_3        matlab.ui.control.CheckBox
        LoadCoilSensitivityMapButton    matlab.ui.control.Button
        LoadSuccessfulCheckBox_2        matlab.ui.control.CheckBox
        LoadB0FieldmapButton            matlab.ui.control.Button
        ImportDataMandatoryPanel        matlab.ui.container.Panel
        LoadSuccessfulCheckBox_5        matlab.ui.control.CheckBox
        ClearDataButton_5               matlab.ui.control.Button
        LoadWaveformButton              matlab.ui.control.Button
        CurrentWaveformFileEditField    matlab.ui.control.EditField
        CurrentWaveformFileEditFieldLabel  matlab.ui.control.Label
        ClearDataButton                 matlab.ui.control.Button
        CurrentDataFileEditField        matlab.ui.control.EditField
        CurrentDataFileEditFieldLabel   matlab.ui.control.Label
        LoadSuccessfulCheckBox          matlab.ui.control.CheckBox
        LoadDataButton                  matlab.ui.control.Button
        SelectPinvReconInputsLabel      matlab.ui.control.Label
        PinvReconAppLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        dd %data
        wf %waveform
        B0Map
        CoilSenseMap
        GradNonLinMap
        
        mtx_reco %matrix size of reconstruction

        mode % method of pseudoinversion of E

        %properties from pinv_recon:
        nslices
        ntimesteps
        ncoils

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Enable/ Disable relevant components:
            app.CurrentDataFileEditField.Editable = "off";
            app.CurrentWaveformFileEditField.Editable = "off";
            app.CurrentB0FieldMapFileEditField.Editable = "off";
            app.CurrentCoilSensitivityMapFileEditField.Editable = "off";
            app.CurrentGradNonLinMapFileEditField.Editable = "off";

            app.LoadSuccessfulCheckBox.Enable = "off";
            app.LoadSuccessfulCheckBox_2.Enable = "off";
            app.LoadSuccessfulCheckBox_3.Enable = "off";
            app.LoadSuccessfulCheckBox_4.Enable = "off";
            app.LoadSuccessfulCheckBox_5.Enable = "off";

            % set default pseudoinversion mode:
            app.mode = app.PinvMethodDropDown.Value; %(Cholesky method)

            % Get folder Pinv-Recon_Dev and add all subfolders to path:
            currentFolder = fileparts(mfilename('fullpath'));
            targetFolder = 'Pinv-Recon_Dev';

            while true
                [parentFolder, currentName] = fileparts(currentFolder);
        
                if strcmp(currentName, targetFolder)
                    % Found
                    rootDirectory = currentFolder;
                    % Add Pinv-Recon_Dev and subfolders to the path
                    addpath(genpath(rootDirectory));
                  %_-------------------------------------------------------
       %% CHECK             %% ADD CHECKS THAT THIS RUNS CORRECTLY
                     % Get box folder:
                    % box_folder = fileparts(rootDirectory);
                    % PINVStartUp(box_folder);
                    PINVStartUp();
                    %add an if error, then app does not open
                  %_-------------------------------------------------------
                    
                    break;
                elseif isempty(parentFolder) || strcmp(currentFolder, parentFolder)
                    % Not found
                    warning('Folder "%s" not found in parent hierarchy.', targetFolderName);
                    return;
                else
                    % Move one level up
                    currentFolder = parentFolder;
                end
            end

            %for now: grad non-lin is not set up, disable options:
            % remove following code when functionality is added
            app.LoadGradNonLinMapButton.Enable = "off";
            app.CurrentGradNonLinMapFileEditField.Enable = "off";
            app.CurrentB0FieldMapFileEditFieldLabel.Enable = "off";
            app.ClearDataButton_4.Enable = "off";
        

            %make corrected recon appear only if maps are present:
            app.ReconCorrectedFig.Visible = "off";
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Load Data  

            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!

            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File');

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
     % NOTE                   % later: add checks for data dimensions??
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                data = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Data file must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.dd = data;
                            app.CurrentDataFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox.Value = 1;
                            app.LoadDataButton.Enable = "off";
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadWaveformButton
        function LoadWaveformButtonPushed(app, event)
            % Load Waveform            
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE  !!!!

            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Waveform File');

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check Waveform is a 1x1 struct with a field 'k'
                        S = load(fullPath);
                        found = false;
                        
                        fields = fieldnames(S);
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isstruct(candidate) && isfield(candidate, 'k')
                                waveform = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'The file must contain a struct with a field "k".', 'Invalid Data');
                            return;
                        else
                            app.wf = waveform;
                            disp(waveform)
                            app.CurrentWaveformFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_5.Value = 1;
                            app.LoadWaveformButton.Enable = "off";
                        end
                       
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadB0FieldmapButton
        function LoadB0FieldmapButtonPushed(app, event)
            % Load B0 (Off Res) FieldMap         
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!

            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File');

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                b0map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'B0 (Off Resonance) Fieldmap must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.B0Map = b0map;
                            app.CurrentB0FieldMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_2.Value = 1;
                            app.LoadB0FieldmapButton.Enable = "off";
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadCoilSensitivityMapButton
        function LoadCoilSensitivityMapButtonPushed(app, event)
            % Load Coil Sensitivity Map        
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!

            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File');

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        % WHAT ELSE SHOUDL THE PROPERTIES OF THIS MAP BE?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                coil_sense_map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Coil Sensitivity Map must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.CoilSenseMap = coil_sense_map;
                            app.CurrentCoilSensitivityMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_3.Value = 1;
                            app.LoadCoilSensitivityMapButton.Enable = "off";
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadGradNonLinMapButton
        function LoadGradNonLinMapButtonPushed(app, event)
            % Load Gradient Non-Linearity Map        
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!

            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File');

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        % WHAT ELSE SHOUDL THE PROPERTIES OF THIS MAP BE?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                grad_non_lin_map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Gradient Non-Linearity Map must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.GradNonLinMap = grad_non_lin_map;
                            app.CurrentGradNonLinMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_4.Value = 1;
                            app.LoadGradNonLinMapButton.Enable = "off";
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Clear Data
            app.dd = [];
            app.CurrentDataFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox.Value = 0;
            app.LoadDataButton.Enable = "on";
        end

        % Button pushed function: ClearDataButton_5
        function ClearDataButton_5Pushed(app, event)
            % Clear Waveform
            app.wf = [];
            app.CurrentWaveformFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_5.Value = 0;
            app.LoadWaveformButton.Enable = "on";
        end

        % Button pushed function: ClearDataButton_2
        function ClearDataButton_2Pushed(app, event)
            % Clear B0 map
            app.B0Map = [];
            app.CurrentB0FieldMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_2.Value = 0;
            app.LoadB0FieldmapButton.Enable = "on";
        end

        % Button pushed function: ClearDataButton_3
        function ClearDataButton_3Pushed(app, event)
            % Clear Coil Sense map
            app.CoilSenseMap = [];
            app.CurrentCoilSensitivityMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_3.Value = 0;
            app.LoadCoilSensitivityMapButton.Enable = "on";
        end

        % Button pushed function: ClearDataButton_4
        function ClearDataButton_4Pushed(app, event)
            % Clear Grad non-lin map
            app.GradNonLinMap = [];
            app.CurrentGradNonLinMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_4.Value = 0;
            app.LoadGradNonLinMapButton.Enable = "on";
        end

        % Button pushed function: GetPinvReconButton
        function GetPinvReconButtonPushed(app, event)
            % GET PINV RECON:
            %% WORK IN PROGRESS

            %progress dialog
            progressBox = uiprogressdlg(app.UIFigure, ...
            'Title','Please wait...', ...
            'Message','Processing...', ...
            'Indeterminate','on');
            
            if isempty(app.dd) && isempty(app.wf)
                close(progressBox); %close dialog  
                uialert(app.UIFigure, 'No data and waveform uploaded!', 'Pinv-Recon Cancelled');
            elseif isempty(app.dd)
                close(progressBox); %close dialog   
                uialert(app.UIFigure, 'No data has been uploaded!', 'Pinv-Recon Cancelled');
            elseif isempty(app.wf)
                close(progressBox); %close dialog   
                uialert(app.UIFigure, 'No waveform has been uploaded!', 'Pinv-Recon Cancelled');
            else
               
                %% ADD CHECKS
                % %check data is correctly uploaded; 
                % if size(app.FieldMap,1)==0
                %      close(progressBox); %close dialog
                %      uiwait(msgbox('No B_0 field map data has been uploaded!'))
                % 
                % elseif size(app.PhantomandWaveform,1) == 0
                %      close(progressBox); %close dialog
                %      uiwait(msgbox('No phantom and waveform data has been uploaded!'))
                % 
                % etc.....
    
                % Is the data 2d or 3d?
                % store data as app property so it can be exported
                
                mtx = app.ReconSizeEditField.Value;            
                % Add checks to ensure mtx_reco value defined is suitable
                % i.e warning if it is above a certain value
                
                % parse pinv-recon inputs using a cell array: 
                %for corrected pinv
                args = {};
                %for uncorrected pinv (no maps)
                args_uncorr = {}; % only want mtx_reco and mode
                if ~isempty(mtx)
                    app.mtx_reco = [mtx mtx]; 
                    args = [args, {'mtx_reco', app.mtx_reco}];
                    args_uncorr = [args_uncorr, {'mtx_reco', app.mtx_reco}];
                end
    
                if ~isempty(app.B0Map)
                    args = [args, {'b0', app.B0Map}];
                end
                if ~isempty(app.CoilSenseMap)
                    args = [args, {'sens', app.CoilSenseMap}];
                end
                if ~isempty(app.GradNonLinMap)
      %% EDIT LINE LATER
                args = [args, {'gradNonLin', app.GradNonLinMap}]; %edit function input when functionality is added
                end
                
                %add mode
                args = [args, {'mode', app.mode}];
                args_uncorr = [args_uncorr, {'mode', app.mode}];
    
                
                % PINV UNCORRECTED
                %% NOTE ADD RECONS TO APP PROPERTIES 
                
                [bb, bbabs] = pinv_recon(app.dd, app.wf ,args_uncorr{:});
                sz =size(bb);    % get the dimensions of bb (x,y,z,t,coils)
                numDims = length(sz);
    
                if numDims == 3                
                    app.nslices = sz(3);
                elseif numDims == 4             
                    app.nslices = sz(3);        
                    app.ntimesteps = sz(4);
                elseif numDims == 5  
                    app.nslices = sz(3);        
                    app.ntimesteps = sz(4);
                    app.ncoils = sz(5);
                end
    
                          
                if ~isempty(app.ntimesteps)
                    disp('Averaging over the time steps.')
                    bbabs = sum(bbabs,4); % sum the timesteps
                end
    
    %             if ~isempty(app.ncoils) && isempty(app.CoilSenseMap)
    %                 % Sum coil data
    % %%                % HOW ARE THESE SUMMED ? ADD CODE
    %                 % root-sum-of-squares coil combination
    %             end          
    % 
                
                %plotting
    
                flattened_image = mat2montage_for_app(bbabs);
                imagesc(app.ReconFig, flattened_image);
    
                % if ~isempty(app.nslices) || app.nslices == 1
                %     if isreal(bbabs)
                %         imagesc(app.ReconFig, bbabs);
                %     else 
                %         imagesc(app.ReconFig, abs(bbabs));
                %     end
                % 
                % else
                %     flattened_image = mat2montage(bbabs);
                %     imagesc(app.ReconFig, flattened_image);
                % end
                
                %--------------------------------------------------------------
                % PINV CORRECTED            
    
                % only do this if there are maps:
                if ~isempty(app.B0Map) || ~isempty(app.CoilSenseMap) || ~isempty(app.GradNonLinMap)
                    [bb_corr, bbabs_corr] = pinv_recon(app.dd, app.wf ,args{:});
                    % [bb_corr, bbabs_corr] = pinv_recon(app.dd, app.wf , 'mtx_reco', app.mtx_reco, 'b0', app.B0Map, 'mode', 'cholesky');
                    if isempty(app.ncoils) || app.ncoils == 1
                        %there is 1 coil
                        disp('there is one coil :) ')
                    end
                    
                    if app.ntimesteps >= 1
                        disp('Summing the time steps!')
                        bbabs_corr = sum(bbabs_corr,4); % sum the timesteps
                    end
                    
                    %plotting
                    app.ReconCorrectedFig.Visible = "on";
                    flattened_image = mat2montage_for_app(bbabs_corr);
                    imagesc(app.ReconCorrectedFig, flattened_image);           
                    
                    close(progressBox); %close dialog   
                else 
                    app.ReconCorrectedFig.Visible = "off";
                    close(progressBox); %close dialog   
                end
            end
        end

        % Value changed function: PinvMethodDropDown
        function PinvMethodDropDownValueChanged(app, event)
            app.mode = app.PinvMethodDropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PinvReconAppLabel = uilabel(app.UIFigure);
            app.PinvReconAppLabel.Text = 'Pinv-Recon App';
            app.SelectPinvReconInputsLabel = uilabel(app.UIFigure);
            app.SelectPinvReconInputsLabel.Text = 'Select Pinv-Recon Inputs';
            app.ImportDataMandatoryPanel = uipanel(app.UIFigure);
            app.ImportDataMandatoryPanel.Title = '1. Import Data (Mandatory)';
            app.LoadDataButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadSuccessfulCheckBox = uicheckbox(app.ImportDataMandatoryPanel);
            app.LoadSuccessfulCheckBox.Text = 'Load Successful';
            app.CurrentDataFileEditFieldLabel = uilabel(app.ImportDataMandatoryPanel);
            app.CurrentDataFileEditFieldLabel.Text = 'Current Data File:';
            app.CurrentDataFileEditField = uieditfield(app.ImportDataMandatoryPanel, 'text');
            app.ClearDataButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.CurrentWaveformFileEditFieldLabel = uilabel(app.ImportDataMandatoryPanel);
            app.CurrentWaveformFileEditFieldLabel.Text = 'Current Waveform File:';
            app.CurrentWaveformFileEditField = uieditfield(app.ImportDataMandatoryPanel, 'text');
            app.LoadWaveformButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.LoadWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveformButtonPushed, true);
            app.LoadWaveformButton.Text = 'Load Waveform';
            app.ClearDataButton_5 = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.ClearDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_5Pushed, true);
            app.ClearDataButton_5.Text = 'Clear Data';
            app.LoadSuccessfulCheckBox_5 = uicheckbox(app.ImportDataMandatoryPanel);
            app.LoadSuccessfulCheckBox_5.Text = 'Load Successful';
            app.ChooseEncodeMechanismsOptionalPanel = uipanel(app.UIFigure);
            app.ChooseEncodeMechanismsOptionalPanel.Title = '2. Choose Encode Mechanisms (Optional)';
            app.LoadB0FieldmapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadB0FieldmapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadB0FieldmapButtonPushed, true);
            app.LoadB0FieldmapButton.Text = 'Load B0 Field map';
            app.LoadSuccessfulCheckBox_2 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_2.Text = 'Load Successful';
            app.LoadCoilSensitivityMapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadCoilSensitivityMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilSensitivityMapButtonPushed, true);
            app.LoadCoilSensitivityMapButton.Text = 'Load Coil Sensitivity Map';
            app.LoadSuccessfulCheckBox_3 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_3.Text = 'Load Successful';
            app.CurrentB0FieldMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentB0FieldMapFileEditFieldLabel.Text = 'Current B0 Field Map File:';
            app.CurrentB0FieldMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.CurrentCoilSensitivityMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentCoilSensitivityMapFileEditFieldLabel.Text = 'Current Coil Sensitivity Map File:';
            app.CurrentCoilSensitivityMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.LoadGradNonLinMapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadGradNonLinMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGradNonLinMapButtonPushed, true);
            app.LoadGradNonLinMapButton.Text = 'Load Grad Non-Lin Map';
            app.LoadSuccessfulCheckBox_4 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_4.Text = 'Load Successful';
            app.CurrentGradNonLinMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentGradNonLinMapFileEditFieldLabel.Text = 'Current Grad Non-Lin Map File:';
            app.CurrentGradNonLinMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.ClearDataButton_2 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_2Pushed, true);
            app.ClearDataButton_2.Text = 'Clear Data';
            app.ClearDataButton_3 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_3Pushed, true);
            app.ClearDataButton_3.Text = 'Clear Data';
            app.ClearDataButton_4 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_4Pushed, true);
            app.ClearDataButton_4.Text = 'Clear Data';
            app.MatrixInversionPanel = uipanel(app.UIFigure);
            app.MatrixInversionPanel.Title = '3. Matrix Inversion';
            app.PinvMethodDropDownLabel = uilabel(app.MatrixInversionPanel);
            app.PinvMethodDropDownLabel.Text = 'Pinv Method';
            app.PinvMethodDropDown = uidropdown(app.MatrixInversionPanel);
            app.PinvMethodDropDown.Items = {'Cholesky', 'SVD', 'Block-Cholesky'};
            app.PinvMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PinvMethodDropDownValueChanged, true);
            app.PinvMethodDropDown.Value = 'Cholesky';
            app.GetPinvReconButton = uibutton(app.MatrixInversionPanel, 'push');
            app.GetPinvReconButton.ButtonPushedFcn = createCallbackFcn(app, @GetPinvReconButtonPushed, true);
            app.GetPinvReconButton.Text = 'Get Pinv-Recon';
            app.ReconSizeEditFieldLabel = uilabel(app.MatrixInversionPanel);
            app.ReconSizeEditFieldLabel.Text = 'Recon Size';
            app.ReconSizeEditField = uieditfield(app.MatrixInversionPanel, 'numeric');
            app.ReconSizeEditField.Limits = [0 Inf];
            app.ReconSizeEditField.RoundFractionalValues = 'on';
            app.ReconSizeEditField.AllowEmpty = 'on';
            app.ReconSizeEditField.Placeholder = 'Default Value';
            app.ReconSizeEditField.Value = [];
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.InputWaveformPanel = uipanel(app.UIFigure);
            app.InputWaveformPanel.Title = 'Input Waveform';
            app.SRFandNoiseMatricesPanel = uipanel(app.UIFigure);
            app.SRFandNoiseMatricesPanel.Title = 'SRF and Noise Matrices';
            app.PinvReconPanel = uipanel(app.UIFigure);
            app.PinvReconPanel.Title = '4. Pinv-Recon';
            app.ReconFig = uiaxes(app.PinvReconPanel);
            title(app.ReconFig, 'Pinv Recon ')
            app.ReconFig.XLimitMethod = 'tight';
            app.ReconFig.YLimitMethod = 'tight';
            app.ReconFig.ZLimitMethod = 'tight';
            colormap(app.ReconFig, 'gray')
            app.ReconCorrectedFig = uiaxes(app.PinvReconPanel);
            title(app.ReconCorrectedFig, 'Pinv Recon Corrected')
            app.ReconCorrectedFig.XLimitMethod = 'tight';
            app.ReconCorrectedFig.YLimitMethod = 'tight';
            app.ReconCorrectedFig.ZLimitMethod = 'tight';
            colormap(app.ReconCorrectedFig, 'gray')
            app.ReconMetricsLabel = uilabel(app.UIFigure);
            app.ReconMetricsLabel.Text = 'Recon Metrics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PinvReconApp_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end