classdef PinvReconApp_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportDataButton                matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        GetPinvReconButton              matlab.ui.control.Button
        PinvReconPanel                  matlab.ui.container.Panel
        ReconTimeLabel                  matlab.ui.control.Label
        ReconTimeCorrectedLabel         matlab.ui.control.Label
        NoiseMatFig_GradOnly            matlab.ui.control.UIAxes
        SRFFig_GradOnly                 matlab.ui.control.UIAxes
        NoiseMatFig                     matlab.ui.control.UIAxes
        SRFFig                          matlab.ui.control.UIAxes
        ReconCorrectedFig               matlab.ui.control.UIAxes
        ReconFig                        matlab.ui.control.UIAxes
        GetResultsLabel                 matlab.ui.control.Label
        MatrixInversionPanel            matlab.ui.container.Panel
        SRFandNoiseMapsSwitch           matlab.ui.control.Switch
        SRFandNoiseMapsSwitchLabel      matlab.ui.control.Label
        UseGPUSwitch                    matlab.ui.control.Switch
        UseGPUSwitchLabel               matlab.ui.control.Label
        ReconSizeEditField              matlab.ui.control.NumericEditField
        ReconSizeEditFieldLabel         matlab.ui.control.Label
        PinvMethodDropDown              matlab.ui.control.DropDown
        PinvMethodDropDownLabel         matlab.ui.control.Label
        ChooseEncodeMechanismsOptionalPanel  matlab.ui.container.Panel
        SensPlotLabel                   matlab.ui.control.Label
        ClearDataButton_4               matlab.ui.control.Button
        ClearDataButton_3               matlab.ui.control.Button
        ClearDataButton_2               matlab.ui.control.Button
        CurrentGradNonLinMapFileEditField  matlab.ui.control.EditField
        CurrentGradNonLinMapFileEditFieldLabel  matlab.ui.control.Label
        LoadSuccessfulCheckBox_4        matlab.ui.control.CheckBox
        LoadGradNonLinMapButton         matlab.ui.control.Button
        CurrentCoilSensitivityMapFileEditField  matlab.ui.control.EditField
        CurrentCoilSensitivityMapFileEditFieldLabel  matlab.ui.control.Label
        CurrentB0FieldMapFileEditField  matlab.ui.control.EditField
        CurrentB0FieldMapFileEditFieldLabel  matlab.ui.control.Label
        LoadSuccessfulCheckBox_3        matlab.ui.control.CheckBox
        LoadCoilSensitivityMapButton    matlab.ui.control.Button
        LoadSuccessfulCheckBox_2        matlab.ui.control.CheckBox
        LoadB0FieldmapButton            matlab.ui.control.Button
        GradNonLinPlot                  matlab.ui.control.UIAxes
        CoilSensePlot                   matlab.ui.control.UIAxes
        B0Plot                          matlab.ui.control.UIAxes
        ImportDataMandatoryPanel        matlab.ui.container.Panel
        LoadSuccessfulCheckBox_5        matlab.ui.control.CheckBox
        ClearDataButton_5               matlab.ui.control.Button
        LoadWaveformButton              matlab.ui.control.Button
        CurrentWaveformFileEditField    matlab.ui.control.EditField
        CurrentWaveformFileEditFieldLabel  matlab.ui.control.Label
        ClearDataButton                 matlab.ui.control.Button
        CurrentDataFileEditField        matlab.ui.control.EditField
        CurrentDataFileEditFieldLabel   matlab.ui.control.Label
        LoadSuccessfulCheckBox          matlab.ui.control.CheckBox
        LoadDataButton                  matlab.ui.control.Button
        WaveformPlot                    matlab.ui.control.UIAxes
        SelectPinvReconInputsLabel      matlab.ui.control.Label
        PinvReconAppLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        % imported data:
        dd %data
       % wf %waveform
        wfn %waveform name
        B0Map
        CoilSenseMap
        GradNonLinMap
        
        % user customised settings
        mtx_reco %matrix size of reconstruction

        mode % method of pseudoinversion of E
        useGPU % GPU used? 0 for false or 1 for true
        getSRFNoise % get maps? 0 for no, 1 for yes

        %properties from pinv_recon:
        nslices
        ntimesteps
        ncoils

        % results
        SRFMatrixGradientOnly
        NoiseMatrixGradientOnly
        SRFMatrix
        NoiseMatrix
        ImageGradientOnly
        ImageWithMaps
        ReconTimeEstimate               %without maps
        ReconTimeEstimateCorrected      %with maps
        ReconTime                       %without maps
        ReconTimeCorrected              %with maps

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Enable/ Disable relevant components:
            app.CurrentDataFileEditField.Editable = "off";
            app.CurrentWaveformFileEditField.Editable = "off";
            app.CurrentB0FieldMapFileEditField.Editable = "off";
            app.CurrentCoilSensitivityMapFileEditField.Editable = "off";
            app.CurrentGradNonLinMapFileEditField.Editable = "off";

            app.LoadSuccessfulCheckBox.Enable = "off";
            app.LoadSuccessfulCheckBox_2.Enable = "off";
            app.LoadSuccessfulCheckBox_3.Enable = "off";
            app.LoadSuccessfulCheckBox_4.Enable = "off";
            app.LoadSuccessfulCheckBox_5.Enable = "off";


            app.ReconTimeLabel.Visible = "off";
            app.ReconTimeCorrectedLabel.Visible = "off";

            % set default pseudoinversion mode:
            app.mode = app.PinvMethodDropDown.Value; %(Cholesky method)
            % set default GPU usage to false;
            app.useGPU = 0;
            % set default get SRF and Noise to false:
            app.getSRFNoise = 0;

            % Get folder Pinv-Recon_Dev and add all subfolders to path:
            currentFolder = fileparts(mfilename('fullpath'));
            targetFolder = 'Pinv-Recon_Dev';

            while true
                [parentFolder, currentName] = fileparts(currentFolder);
        
                if strcmp(currentName, targetFolder)
                    % Found
                    rootDirectory = currentFolder;
                    % Add Pinv-Recon_Dev and subfolders to the path
                    addpath(genpath(rootDirectory));
                  
                    cd(rootDirectory)
               %     boxFolder = fileparts(rootDirectory);
                %    PINVStartUp();

                    %add an if error, then app does not open
                  %_-------------------------------------------------------
                    
                    break;
                elseif isempty(parentFolder) || strcmp(currentFolder, parentFolder)
                    % Not found
                    uialert(app.UIFigure, ...
                        sprintf('Folder "%s" not found in parent hierarchy. Startup condition failed. Closing app.', targetFolderName), ...
                        'Startup Error');
                    delete(app)
                    return;
                else
                    % Move one level up
                    currentFolder = parentFolder;
                end
            end

            %for now: grad non-lin is not set up, disable options:
            % remove following code block when functionality is added
            app.LoadGradNonLinMapButton.Enable = "off";
            app.CurrentGradNonLinMapFileEditField.Enable = "off";
            app.CurrentB0FieldMapFileEditFieldLabel.Enable = "off";
            app.ClearDataButton_4.Enable = "off";
        
            
            % Hide optional plots, so they only appear when filled:
            app.ReconCorrectedFig.Visible = "off";
            app.B0Plot.Visible = "off";
            app.CoilSensePlot.Visible = "off";
            app.SensPlotLabel.Visible = "off";
            app.GradNonLinPlot.Visible = "off";
            app.SRFFig.Visible = "off";
            app.NoiseMatFig.Visible = "off";
            app.SRFFig_GradOnly.Visible = "off";
            app.NoiseMatFig_GradOnly.Visible = "off";


        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Load Data  

            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!
            importDir = fullfile(pwd, 'Pinv-Recon_App/Data_for_app/');
            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File', importDir);
               
           % fullfile(exportDir, '*.mat')


            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
     % NOTE                   % later: add checks for data dimensions??
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                data = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Data file must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.dd = data;
                            app.CurrentDataFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox.Value = 1;
                            app.LoadDataButton.Enable = "off";
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadWaveformButton
        function LoadWaveformButtonPushed(app, event)
            % Load Waveform            
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE  !!!!
            importDir = fullfile(pwd, 'Pinv-Recon_App/Data_for_app/');
            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Waveform File', importDir);

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check Waveform is a 1x1 struct with a field 'k'
                        % S = load(fullPath);
                        % found = false;
                        % 
                        % fields = fieldnames(S);
                        % for i = 1:numel(fields)
                        %     candidate = S.(fields{i});
                        %     if isstruct(candidate) && isfield(candidate, 'k')
                        %         waveform = candidate;
                        %         found = true;
                        %         break;
                        %     end
                        % end
                        % 
                        % if ~found
                        %     uialert(app.UIFigure, 'The file must contain a struct with a field "k".', 'Invalid Data');
                        %     return;
                        % else
                            %app.wf = waveform;

                            app.wfn = fullPath;
                            app.CurrentWaveformFileEditField.Value = fullPath;                             
                            app.LoadSuccessfulCheckBox_5.Value = 1;
                            app.LoadWaveformButton.Enable = "off";

                            %plot waveform
            %% MAKE this more versatile, ie, if kx and ky are both real, or more than 2D
                            wf = load_waveform(app.wfn);
                            
                            %get mtx_reco for filename:
                            if ~isfield(wf,'mtx') && ~isfield(wf,'npix');error('Acquisition matrix size is required in wf.mtx or wf.npix');end
                            try mtx_acq=wf.mtx; catch; mtx_acq=wf.npix; end
                            if length(mtx_acq)==1;mtx_acq=[mtx_acq mtx_acq];end

                    %% UNOCMMENT THIS LINE LATER!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                            app.mtx_reco=mtx_acq;


                            if ~isreal(wf.k)
                                kx = real(wf.k);
                                ky = imag(wf.k);                            
                                plot(app.WaveformPlot, kx, ky);
                            else
                                wf_k_2d = squeeze(wf.k);
                                kx = wf_k_2d(:,1);
                                ky = wf_k_2d(:,2);                            
                                plot(app.WaveformPlot, kx, ky);
                            end

                            % if ~isreal(app.wf.k)
                            %     kx = real(app.wf.k);
                            %     ky = imag(app.wf.k);                            
                            %     plot(app.WaveformPlot, kx, ky);
                            % else
                            %     wf_k_2d = squeeze(app.wf.k);
                            %     kx = wf_k_2d(:,1);
                            %     ky = wf_k_2d(:,2);                            
                            %     plot(app.WaveformPlot, kx, ky);
                            % end

                       % end
                       
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadB0FieldmapButton
        function LoadB0FieldmapButtonPushed(app, event)
            % Load B0 (Off Res) FieldMap         
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!
            importDir = fullfile(pwd, 'Pinv-Recon_App/Data_for_app/');
            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File', importDir);

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                b0map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'B0 (Off Resonance) Fieldmap must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.B0Map = b0map;
                            app.CurrentB0FieldMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_2.Value = 1;
                            app.LoadB0FieldmapButton.Enable = "off";
                            %plot map:
                            app.B0Plot.Visible = "on";
                            flat_B0Plot = mat2montage_for_app(app.B0Map);
                            imagesc(app.B0Plot, flat_B0Plot)
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadCoilSensitivityMapButton
        function LoadCoilSensitivityMapButtonPushed(app, event)
            % Load Coil Sensitivity Map        
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!
            importDir = fullfile(pwd, 'Pinv-Recon_App/Data_for_app/');
            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File', importDir);

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        % WHAT ELSE SHOUDL THE PROPERTIES OF THIS MAP BE?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                coil_sense_map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Coil Sensitivity Map must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.CoilSenseMap = coil_sense_map;
                            app.CurrentCoilSensitivityMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_3.Value = 1;
                            app.LoadCoilSensitivityMapButton.Enable = "off";

                            %plot map:
                            app.CoilSensePlot.Visible = "on";

                            if ndims(app.CoilSenseMap) == 2
                                imagesc(app.CoilSensePlot, abs(app.CoilSenseMap))

                            elseif ndims(app.CoilSenseMap) == 3

                                flat_CoilSensePlot = mat2montage_for_app(abs(app.CoilSenseMap));
                                imagesc(app.CoilSensePlot, flat_CoilSensePlot)

                            elseif ndims(app.CoilSenseMap) == 4
                                %disp only 1 coil, for nslices    
                                flat_CoilSensePlot = mat2montage_for_app(abs(app.CoilSenseMap(:,:,:,1)));
                                imagesc(app.CoilSensePlot, flat_CoilSensePlot)
                                
                                str = sprintf("Displaying coil 1 of %d", size(app.CoilSenseMap,4));
                                app.SensPlotLabel.Text = str;
                                app.SensPlotLabel.Visible = "on";

                            else
                                % print error: data must be 2d, 3d, or 4d
                                uialert(app.UIFigure, 'Coil sensitivity map cannot have more than 4 dimensions.', 'Invalid Data');
                            end
                            
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: LoadGradNonLinMapButton
        function LoadGradNonLinMapButtonPushed(app, event)
            % Load Gradient Non-Linearity Map        
            %% ADD VERIFICATION THAT FILE TYPE IS SUITABLE !!!!
            importDir = fullfile(pwd, 'Pinv-Recon_App/Data_for_app/');
            [file, path] = uigetfile({'*.mat', 'Data Files (*.mat)'}, 'Select a Data File', importDir);

            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        % Check the data is of type numeric
    %NOTE                    % Later: Add check that dims match data (or
    %should this be checked when doing actual recon?
                        % WHAT ELSE SHOUDL THE PROPERTIES OF THIS MAP BE?
                        S = load(fullPath);
                        fields = fieldnames(S);
                        found = false;
                        for i = 1:numel(fields)
                            candidate = S.(fields{i});
                            if isnumeric(candidate)
                                grad_non_lin_map = candidate;
                                found = true;
                                break;
                            end
                        end
                        
                        if ~found
                            uialert(app.UIFigure, 'Gradient Non-Linearity Map must be of type numeric.', 'Invalid Data');
                            return;
                        else
                            app.GradNonLinMap = grad_non_lin_map;
                            app.CurrentGradNonLinMapFileEditField.Value = fullPath; 
                            app.LoadSuccessfulCheckBox_4.Value = 1;
                            app.LoadGradNonLinMapButton.Enable = "off";
                            %plot map:
                            app.GradNonLinPlot.Visible = "on";
                            flat_GranNonLinPlot = mat2montage_for_app(app.GradNonLinMap);
                            imagesc(app.GradNonLinPlot, flat_GranNonLinPlot)
                        end
                    otherwise
                        uialert(app.UIFigure, 'Unsupported file type.', 'Error');
                        return;
                end                              
            end
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            % Clear Data
            app.dd = [];
            app.CurrentDataFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox.Value = 0;
            app.LoadDataButton.Enable = "on";
        end

        % Button pushed function: ClearDataButton_5
        function ClearDataButton_5Pushed(app, event)
            % Clear Waveform
        %    app.wf = [];
            app.wfn = [];
            app.CurrentWaveformFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_5.Value = 0;
            app.LoadWaveformButton.Enable = "on";
            cla(app.WaveformPlot);
        end

        % Button pushed function: ClearDataButton_2
        function ClearDataButton_2Pushed(app, event)
            % Clear B0 map
            app.B0Map = [];
            app.CurrentB0FieldMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_2.Value = 0;
            app.LoadB0FieldmapButton.Enable = "on";
            cla(app.B0Plot);
            app.B0Plot.Visible = "off";
        end

        % Button pushed function: ClearDataButton_3
        function ClearDataButton_3Pushed(app, event)
            % Clear Coil Sense map
            app.CoilSenseMap = [];
            app.CurrentCoilSensitivityMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_3.Value = 0;
            app.LoadCoilSensitivityMapButton.Enable = "on";
            cla(app.CoilSensePlot);
            app.CoilSensePlot.Visible = "off";
        end

        % Button pushed function: ClearDataButton_4
        function ClearDataButton_4Pushed(app, event)
            % Clear Grad non-lin map
            app.GradNonLinMap = [];
            app.CurrentGradNonLinMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_4.Value = 0;
            app.LoadGradNonLinMapButton.Enable = "on";
            cla(app.GradNonLinMap);
            app.GradNonLinPlot.Visible = "off";
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            % CLEAR ALL:

            % Clear Data
            app.dd = [];
            app.CurrentDataFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox.Value = 0;
            app.LoadDataButton.Enable = "on";

            % Clear Waveform
       %     app.wf = [];
            app.wfn = [];
            app.CurrentWaveformFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_5.Value = 0;
            app.LoadWaveformButton.Enable = "on";
            cla(app.WaveformPlot);

            % Clear B0 map
            app.B0Map = [];
            app.CurrentB0FieldMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_2.Value = 0;
            app.LoadB0FieldmapButton.Enable = "on";
            cla(app.B0Plot);
            app.B0Plot.Visible = "off";

            % Clear Coil Sense map
            app.CoilSenseMap = [];
            app.CurrentCoilSensitivityMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_3.Value = 0;
            app.LoadCoilSensitivityMapButton.Enable = "on";
            cla(app.CoilSensePlot);
            app.CoilSensePlot.Visible = "off";

            % Clear Grad non-lin map
            app.GradNonLinMap = [];
            app.CurrentGradNonLinMapFileEditField.Value = ""; 
            app.LoadSuccessfulCheckBox_4.Value = 0;
            app.LoadGradNonLinMapButton.Enable = "on";
            cla(app.GradNonLinMap);
            app.GradNonLinPlot.Visible = "off";

            % Clear recons and plots:
            cla(app.ReconFig);
            cla(app.ReconCorrectedFig);
            cla(app.SRFFig);
            cla(app.NoiseMatFig);
            cla(app.SRFFig_GradOnly);
            cla(app.NoiseMatFig_GradOnly);
            % Clear colorbars for SRF and Noise plots:
            cbs1 = findall(app.SRFFig.Parent, 'Type', 'ColorBar');
            delete(cbs1);
            cbs2 = findall(app.NoiseMatFig.Parent, 'Type', 'ColorBar');
            delete(cbs2);
            cbs3 = findall(app.SRFFig_GradOnly.Parent, 'Type', 'ColorBar');
            delete(cbs3);
            cbs4 = findall(app.NoiseMatFig_GradOnly.Parent, 'Type', 'ColorBar');
            delete(cbs4);


          %  app.ReconSizeEditField.Value = []; %% CHANGE THIS LATER? Nan and [] did not work.
            app.PinvMethodDropDown.Value = "Cholesky";
            app.UseGPUSwitch.Value = 'Off';
            app.SRFandNoiseMapsSwitch.Value = 'Off';
            app.SRFFig.Visible = "off";
            app.NoiseMatFig.Visible = "off";
            app.SRFFig_GradOnly.Visible = "off";
            app.NoiseMatFig_GradOnly.Visible = "off";

            % Clear results:            
            app.SRFMatrixGradientOnly = [];
            app.NoiseMatrixGradientOnly = [];
            app.SRFMatrix = [];
            app.NoiseMatrix = [];
            app.ImageGradientOnly = [];
            app.ImageWithMaps = [];
            app.ReconTimeEstimate =[];
            app.ReconTimeEstimateCorrected =[];
            app.ReconTime = [];
            app.ReconTimeCorrected = [];

            app.ReconTimeLabel.Visible = "off";
            app.ReconTimeCorrectedLabel.Visible = "off";
            
            
            % Other things to reset:
            app.ReconCorrectedFig.Visible = "off";
            % set default pseudoinversion mode:
            app.mode = app.PinvMethodDropDown.Value; %(Cholesky method)
            % set default GPU usage to false;
            app.useGPU = 0;
        end

        % Value changed function: PinvMethodDropDown
        function PinvMethodDropDownValueChanged(app, event)
            app.mode = app.PinvMethodDropDown.Value;
        end

        % Value changed function: UseGPUSwitch
        function UseGPUSwitchValueChanged(app, event)
            GPU_switch_value = app.UseGPUSwitch.Value;  
            % check their computer can use GPU
            if GPU_switch_value == "On"
                app.useGPU = 1;
                if canUseGPU == 0
                    app.useGPU = 0;
                    app.UseGPUSwitch.Value = "Off";
                    uialert(app.UIFigure, 'No available GPU, or no recent GPU driver is present, or Parallel Computing Toolbox not installed.', 'GPU not found');
                end
            elseif GPU_switch_value == "Off"
                app.useGPU = 0;
            end

        end

        % Value changed function: SRFandNoiseMapsSwitch
        function SRFandNoiseMapsSwitchValueChanged(app, event)
            SRFNoise_switch_value = app.SRFandNoiseMapsSwitch.Value;

    %% Add check: enough memory to store??
            if SRFNoise_switch_value == "On"
                app.getSRFNoise = 1;
                app.SRFFig_GradOnly.Visible = "on";
                app.NoiseMatFig_GradOnly.Visible = "on";

            elseif SRFNoise_switch_value ==  "Off"
                app.getSRFNoise = 0;
                app.SRFFig.Visible = "off";
                app.NoiseMatFig.Visible = "off";
                app.SRFFig_GradOnly.Visible = "off";
                app.NoiseMatFig_GradOnly.Visible = "off";
                
            end

        end

        % Button pushed function: GetPinvReconButton
        function GetPinvReconButtonPushed(app, event)
            % GET PINV RECON:
            %% WORK IN PROGRESS

            %progress dialog
            progressBox = uiprogressdlg(app.UIFigure, ...
            'Title','Please wait...', ...
            'Message','Processing...', ...
            'Indeterminate','on');
            
            if isempty(app.dd) && isempty(app.wfn)
                close(progressBox); %close dialog  
                uialert(app.UIFigure, 'No data and waveform uploaded!', 'Pinv-Recon Cancelled');
            elseif isempty(app.dd)
                close(progressBox); %close dialog   
                uialert(app.UIFigure, 'No data has been uploaded!', 'Pinv-Recon Cancelled');
            elseif isempty(app.wfn)
                close(progressBox); %close dialog   
                uialert(app.UIFigure, 'No waveform has been uploaded!', 'Pinv-Recon Cancelled');
            else
               
                %% ADD CHECKS
                % %check data is correctly uploaded; 
                % if size(app.FieldMap,1)==0
                %      close(progressBox); %close dialog
                %      uiwait(msgbox('No B_0 field map data has been uploaded!'))
                % 
                % elseif size(app.PhantomandWaveform,1) == 0
                %      close(progressBox); %close dialog
                %      uiwait(msgbox('No phantom and waveform data has been uploaded!'))
                % 
                % etc.....
    
                % Is the data 2d or 3d?
                % store data as app property so it can be exported
                
                mtx = app.ReconSizeEditField.Value;  
            
                % Add checks to ensure mtx_reco value defined is suitable
                % i.e warning if it is above a certain value
                
                % parse pinv-recon inputs using a cell array: 
                %for corrected pinv
                args = {};
                %for uncorrected pinv (no maps)
                args_uncorr = {}; % dont add encode maps here
                if ~isempty(mtx)
                    app.mtx_reco = [mtx mtx]; 
                    args = [args, {'mtx_reco', app.mtx_reco}];
                    args_uncorr = [args_uncorr, {'mtx_reco', app.mtx_reco}];
                end
    
                if ~isempty(app.B0Map)
                    args = [args, {'b0', app.B0Map}];
                end
                if ~isempty(app.CoilSenseMap)
                    args = [args, {'sens', app.CoilSenseMap}];
                end
                if ~isempty(app.GradNonLinMap)
      %% EDIT LINE LATER
                args = [args, {'gradNonLin', app.GradNonLinMap}]; %edit function input when functionality is added
                end

                %add mode and gpu
                args = [args, {'mode', app.mode}, {'usegpu', app.useGPU }, {'saveSRFNoise', app.getSRFNoise}];
                args_uncorr = [args_uncorr, {'mode', app.mode}, {'usegpu', app.useGPU}, {'saveSRFNoise', app.getSRFNoise}];    
                
                %print waveform path
                fprintf('Waveform path is: %s\n', app.wfn);

                % PINV UNCORRECTED
                %% NOTE ADD RECONS TO APP PROPERTIES 
                
                tic
                [bb, bbabs] = pinv_recon(app.dd, app.wfn ,args_uncorr{:});
                app.ImageGradientOnly = bb;
                sz =size(bb);    % get the dimensions of bb (x,y,z,t,coils)
                numDims = length(sz);
    
                if numDims == 3                
                    app.nslices = sz(3);
                elseif numDims == 4             
                    app.nslices = sz(3);        
                    app.ntimesteps = sz(4);
                elseif numDims == 5  
                    app.nslices = sz(3);        
                    app.ntimesteps = sz(4);
                    app.ncoils = sz(5);
                end
    
                          
                if ~isempty(app.ntimesteps)
                    disp('Averaging over the time steps.')
                    bbabs = sum(bbabs,4); % sum the timesteps
                end
    
    %             if ~isempty(app.ncoils) && isempty(app.CoilSenseMap)
    %                 % Sum coil data
    % %%                % HOW ARE THESE SUMMED ? ADD CODE
    %                 % root-sum-of-squares coil combination
    %             end          
    % 
                
                %plotting

                app.ReconTime = toc;
                str = sprintf("Reconstruction Time = %.4f seconds.", app.ReconTime);
                app.ReconTimeLabel.Text = str;
                app.ReconTimeLabel.Visible = "on";

     
                flattened_image = mat2montage_for_app(bbabs);
                imagesc(app.ReconFig, flattened_image);
                
                if app.getSRFNoise == 1                
                    % Get save location of SRFNoise:
                    fnstr = [];                
                    [dataFolder, ~, ~] = fileparts(app.wfn);                  
                    SRFNoiseFolder_GradOnly = [dataFolder '/SRFNoise'  num2str(app.mtx_reco(1)) fnstr '.mat'];  
                    
                    % load in data
                    SRFNoiseGradientOnly = load(SRFNoiseFolder_GradOnly);
                    app.SRFMatrixGradientOnly = SRFNoiseGradientOnly.SRF;
                    app.NoiseMatrixGradientOnly = SRFNoiseGradientOnly.NoiseMat;
                
                    % plotting
                    SRFGradientOnly = abs(mat2montage_for_app(app.SRFMatrixGradientOnly));
                    NoiseGradientOnly = mat2montage_for_app(app.NoiseMatrixGradientOnly);
                    imagesc(app.SRFFig_GradOnly, SRFGradientOnly),  colorbar(app.SRFFig_GradOnly); 
                    imagesc(app.NoiseMatFig_GradOnly, NoiseGradientOnly),  colorbar(app.NoiseMatFig_GradOnly); 
                
                end

                % if ~isempty(app.nslices) || app.nslices == 1
                %     if isreal(bbabs)
                %         imagesc(app.ReconFig, bbabs);
                %     else 
                %         imagesc(app.ReconFig, abs(bbabs));
                %     end
                % 
                % else
                %     flattened_image = mat2montage(bbabs);
                %     imagesc(app.ReconFig, flattened_image);
                % end
                
                %--------------------------------------------------------------
                % PINV CORRECTED            
    
                % only do this if there are maps:
                if ~isempty(app.B0Map) || ~isempty(app.CoilSenseMap) || ~isempty(app.GradNonLinMap)
                    tic
                    [bb_corr, bbabs_corr] = pinv_recon(app.dd, app.wfn ,args{:});
                    app.ImageWithMaps = bb_corr;
                    % [bb_corr, bbabs_corr] = pinv_recon(app.dd, app.wfn , 'mtx_reco', app.mtx_reco, 'b0', app.B0Map, 'mode', 'cholesky');
                    if isempty(app.ncoils) || app.ncoils == 1
                        %there is 1 coil
                        disp('there is one coil :) ')
                    end

                    if app.ntimesteps >= 1
                        disp('Summing the time steps!')
                        bbabs_corr = sum(bbabs_corr,4); % sum the timesteps
                    end
                    
                    app.ReconTimeCorrected = toc;
                    str2 = sprintf("Reconstruction Time = %.4f seconds.", app.ReconTimeCorrected);
                    app.ReconTimeCorrectedLabel.Text = str2;
                    app.ReconTimeCorrectedLabel.Visible = "on";

                    %plotting
                    app.ReconCorrectedFig.Visible = "on";
                    flattened_image = mat2montage_for_app(bbabs_corr);
                    imagesc(app.ReconCorrectedFig, flattened_image);           

                    close(progressBox); %close dialog 
                    
                     %% SRF AND NOISE

                    if app.getSRFNoise == 1
                        app.SRFFig.Visible = "on";
                        app.NoiseMatFig.Visible = "on";
    
                        % Get save location of SRFNoise:
                        fnstr = [];                
             %           [dataFolder, ~, ~] = fileparts(app.wfn);         % in theory, i do not need this line         
    
                        if ~isempty(app.B0Map);fnstr=[fnstr '_b0'];end
                        if ~isempty(app.CoilSenseMap);fnstr=[fnstr '_sens'];end

                        SRFNoiseFolder = [dataFolder '/SRFNoise' num2str(app.mtx_reco(1)) fnstr '.mat'];  
                        
                        % load in data
                        SRFNoise = load(SRFNoiseFolder);
                        app.SRFMatrix = SRFNoise.SRF;
                        app.NoiseMatrix = SRFNoise.NoiseMat;
    
                        % plotting
                        SRF = abs(mat2montage_for_app(app.SRFMatrix));
                        Noise = mat2montage_for_app(app.NoiseMatrix);
                        imagesc(app.SRFFig, SRF),  colorbar(app.SRFFig); 
                        imagesc(app.NoiseMatFig, Noise),  colorbar(app.NoiseMatFig); 
    
                    end

                else 
                    app.ReconCorrectedFig.Visible = "off";
                    close(progressBox); %close dialog   
                end

               
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            propertyNames = {
                    'dd', ...
                    'wfn', ...
                    'B0Map', ...
                    'CoilSenseMap', ...
                    'GradNonLinMap', ...
                    'mtx_reco', ...
                    'mode', ...
                    'useGPU', ...
                    'getSRFNoise', ...
                    'nslices', ...
                    'ntimesteps', ...
                    'ncoils', ...
                    'SRFMatrixGradientOnly', ...
                    'NoiseMatrixGradientOnly', ...
                    'SRFMatrix', ...
                    'NoiseMatrix', ...
                    'ImageGradientOnly', ...
                    'ImageWithMaps', ...
                    'ReconTimeEstimate', ...
                    'ReconTimeEstimateCorrected', ...
                    'ReconTime', ...
                    'ReconTimeCorrected' ...
                     };
            
            % save to wfn folder
            [dataFolder, ~, ~] = fileparts(app.wfn);  
            
            [file, path] = uiputfile('appData.mat', 'Save App Data', dataFolder);
            if isequal(file, 0)
                return;  % user canceled
            end
            
            % Build struct of propeties to export
            PinvReconData = struct();
            for i = 1:numel(propertyNames)
                propName = propertyNames{i};
                try
                    PinvReconData.(propName) = app.(propName);
                catch
                    warning('Could not export property: %s', propName);
                end
            end
            
            % Save to file
            save(fullfile(path, file), 'PinvReconData');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PinvReconAppLabel = uilabel(app.UIFigure);
            app.PinvReconAppLabel.Text = 'Pinv-Recon App';
            app.SelectPinvReconInputsLabel = uilabel(app.UIFigure);
            app.SelectPinvReconInputsLabel.Text = 'Select Pinv-Recon Inputs';
            app.ImportDataMandatoryPanel = uipanel(app.UIFigure);
            app.ImportDataMandatoryPanel.Title = '1. Import Data (Mandatory)';
            app.WaveformPlot = uiaxes(app.ImportDataMandatoryPanel);
            title(app.WaveformPlot, 'Waveform')
            xlabel(app.WaveformPlot, 'kx')
            ylabel(app.WaveformPlot, 'ky')
            app.WaveformPlot.XLimitMethod = 'tight';
            app.WaveformPlot.YLimitMethod = 'tight';
            app.WaveformPlot.ZLimitMethod = 'tight';
            app.LoadDataButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LoadSuccessfulCheckBox = uicheckbox(app.ImportDataMandatoryPanel);
            app.LoadSuccessfulCheckBox.Text = 'Load Successful';
            app.CurrentDataFileEditFieldLabel = uilabel(app.ImportDataMandatoryPanel);
            app.CurrentDataFileEditFieldLabel.Text = 'Current Data File:';
            app.CurrentDataFileEditField = uieditfield(app.ImportDataMandatoryPanel, 'text');
            app.ClearDataButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.CurrentWaveformFileEditFieldLabel = uilabel(app.ImportDataMandatoryPanel);
            app.CurrentWaveformFileEditFieldLabel.Text = 'Current Waveform File:';
            app.CurrentWaveformFileEditField = uieditfield(app.ImportDataMandatoryPanel, 'text');
            app.LoadWaveformButton = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.LoadWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @LoadWaveformButtonPushed, true);
            app.LoadWaveformButton.Text = 'Load Waveform';
            app.ClearDataButton_5 = uibutton(app.ImportDataMandatoryPanel, 'push');
            app.ClearDataButton_5.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_5Pushed, true);
            app.ClearDataButton_5.Text = 'Clear Data';
            app.LoadSuccessfulCheckBox_5 = uicheckbox(app.ImportDataMandatoryPanel);
            app.LoadSuccessfulCheckBox_5.Text = 'Load Successful';
            app.ChooseEncodeMechanismsOptionalPanel = uipanel(app.UIFigure);
            app.ChooseEncodeMechanismsOptionalPanel.Title = '2. Choose Encode Mechanisms (Optional)';
            app.B0Plot = uiaxes(app.ChooseEncodeMechanismsOptionalPanel);
            title(app.B0Plot, 'B0 Filedmap')
            app.B0Plot.XLimitMethod = 'tight';
            app.B0Plot.YLimitMethod = 'tight';
            app.B0Plot.ZLimitMethod = 'tight';
            colormap(app.B0Plot, 'gray')
            app.CoilSensePlot = uiaxes(app.ChooseEncodeMechanismsOptionalPanel);
            title(app.CoilSensePlot, 'Coil Sense Map')
            app.CoilSensePlot.XLimitMethod = 'tight';
            app.CoilSensePlot.YLimitMethod = 'tight';
            app.CoilSensePlot.ZLimitMethod = 'tight';
            colormap(app.CoilSensePlot, 'gray')
            app.GradNonLinPlot = uiaxes(app.ChooseEncodeMechanismsOptionalPanel);
            title(app.GradNonLinPlot, 'Grad non-lin Map')
            app.GradNonLinPlot.XLimitMethod = 'tight';
            app.GradNonLinPlot.YLimitMethod = 'tight';
            app.GradNonLinPlot.ZLimitMethod = 'tight';
            colormap(app.GradNonLinPlot, 'gray')
            app.LoadB0FieldmapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadB0FieldmapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadB0FieldmapButtonPushed, true);
            app.LoadB0FieldmapButton.Text = 'Load B0 Field map';
            app.LoadSuccessfulCheckBox_2 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_2.Text = 'Load Successful';
            app.LoadCoilSensitivityMapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadCoilSensitivityMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilSensitivityMapButtonPushed, true);
            app.LoadCoilSensitivityMapButton.Text = 'Load Coil Sensitivity Map';
            app.LoadSuccessfulCheckBox_3 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_3.Text = 'Load Successful';
            app.CurrentB0FieldMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentB0FieldMapFileEditFieldLabel.Text = 'Current B0 Field Map File:';
            app.CurrentB0FieldMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.CurrentCoilSensitivityMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentCoilSensitivityMapFileEditFieldLabel.Text = 'Current Coil Sensitivity Map File:';
            app.CurrentCoilSensitivityMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.LoadGradNonLinMapButton = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.LoadGradNonLinMapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGradNonLinMapButtonPushed, true);
            app.LoadGradNonLinMapButton.Text = 'Load Grad Non-Lin Map';
            app.LoadSuccessfulCheckBox_4 = uicheckbox(app.ChooseEncodeMechanismsOptionalPanel);
            app.LoadSuccessfulCheckBox_4.Text = 'Load Successful';
            app.CurrentGradNonLinMapFileEditFieldLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.CurrentGradNonLinMapFileEditFieldLabel.Text = 'Current Grad Non-Lin Map File:';
            app.CurrentGradNonLinMapFileEditField = uieditfield(app.ChooseEncodeMechanismsOptionalPanel, 'text');
            app.ClearDataButton_2 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_2Pushed, true);
            app.ClearDataButton_2.Text = 'Clear Data';
            app.ClearDataButton_3 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_3Pushed, true);
            app.ClearDataButton_3.Text = 'Clear Data';
            app.ClearDataButton_4 = uibutton(app.ChooseEncodeMechanismsOptionalPanel, 'push');
            app.ClearDataButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_4Pushed, true);
            app.ClearDataButton_4.Text = 'Clear Data';
            app.SensPlotLabel = uilabel(app.ChooseEncodeMechanismsOptionalPanel);
            app.MatrixInversionPanel = uipanel(app.UIFigure);
            app.MatrixInversionPanel.Title = '3. Matrix Inversion';
            app.PinvMethodDropDownLabel = uilabel(app.MatrixInversionPanel);
            app.PinvMethodDropDownLabel.Text = 'Pinv Method';
            app.PinvMethodDropDown = uidropdown(app.MatrixInversionPanel);
            app.PinvMethodDropDown.Items = {'Cholesky', 'SVD', 'EIG', 'QR'};
            app.PinvMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PinvMethodDropDownValueChanged, true);
            app.PinvMethodDropDown.Value = 'Cholesky';
            app.ReconSizeEditFieldLabel = uilabel(app.MatrixInversionPanel);
            app.ReconSizeEditFieldLabel.Text = 'Recon Size';
            app.ReconSizeEditField = uieditfield(app.MatrixInversionPanel, 'numeric');
            app.ReconSizeEditField.Limits = [0 Inf];
            app.ReconSizeEditField.RoundFractionalValues = 'on';
            app.ReconSizeEditField.AllowEmpty = 'on';
            app.ReconSizeEditField.Placeholder = 'Default Value';
            app.ReconSizeEditField.Value = [];
            app.UseGPUSwitchLabel = uilabel(app.MatrixInversionPanel);
            app.UseGPUSwitchLabel.Text = 'Use GPU';
            app.UseGPUSwitch = uiswitch(app.MatrixInversionPanel, 'slider');
            app.UseGPUSwitch.ValueChangedFcn = createCallbackFcn(app, @UseGPUSwitchValueChanged, true);
            app.SRFandNoiseMapsSwitchLabel = uilabel(app.MatrixInversionPanel);
            app.SRFandNoiseMapsSwitchLabel.Text = 'SRF and Noise Maps';
            app.SRFandNoiseMapsSwitch = uiswitch(app.MatrixInversionPanel, 'slider');
            app.SRFandNoiseMapsSwitch.ValueChangedFcn = createCallbackFcn(app, @SRFandNoiseMapsSwitchValueChanged, true);
            app.GetResultsLabel = uilabel(app.UIFigure);
            app.GetResultsLabel.Text = 'Get Results';
            app.PinvReconPanel = uipanel(app.UIFigure);
            app.PinvReconPanel.Title = '4. Pinv-Recon';
            app.ReconFig = uiaxes(app.PinvReconPanel);
            title(app.ReconFig, 'Pinv Recon ')
            app.ReconFig.XLimitMethod = 'tight';
            app.ReconFig.YLimitMethod = 'tight';
            app.ReconFig.ZLimitMethod = 'tight';
            colormap(app.ReconFig, 'gray')
            app.ReconCorrectedFig = uiaxes(app.PinvReconPanel);
            title(app.ReconCorrectedFig, 'Pinv Recon Corrected')
            app.ReconCorrectedFig.XLimitMethod = 'tight';
            app.ReconCorrectedFig.YLimitMethod = 'tight';
            app.ReconCorrectedFig.ZLimitMethod = 'tight';
            colormap(app.ReconCorrectedFig, 'gray')
            app.SRFFig = uiaxes(app.PinvReconPanel);
            title(app.SRFFig, 'SRF')
            app.SRFFig.XLimitMethod = 'tight';
            app.SRFFig.YLimitMethod = 'tight';
            app.SRFFig.ZLimitMethod = 'tight';
            colormap(app.SRFFig, 'hot')
            app.NoiseMatFig = uiaxes(app.PinvReconPanel);
            title(app.NoiseMatFig, 'Noise Mat')
            app.NoiseMatFig.XLimitMethod = 'tight';
            app.NoiseMatFig.YLimitMethod = 'tight';
            app.NoiseMatFig.ZLimitMethod = 'tight';
            colormap(app.NoiseMatFig, 'hot')
            app.SRFFig_GradOnly = uiaxes(app.PinvReconPanel);
            title(app.SRFFig_GradOnly, 'SRF')
            app.SRFFig_GradOnly.XLimitMethod = 'tight';
            app.SRFFig_GradOnly.YLimitMethod = 'tight';
            app.SRFFig_GradOnly.ZLimitMethod = 'tight';
            colormap(app.SRFFig_GradOnly, 'hot')
            app.NoiseMatFig_GradOnly = uiaxes(app.PinvReconPanel);
            title(app.NoiseMatFig_GradOnly, 'Noise Mat')
            app.NoiseMatFig_GradOnly.XLimitMethod = 'tight';
            app.NoiseMatFig_GradOnly.YLimitMethod = 'tight';
            app.NoiseMatFig_GradOnly.ZLimitMethod = 'tight';
            colormap(app.NoiseMatFig_GradOnly, 'hot')
            app.ReconTimeCorrectedLabel = uilabel(app.PinvReconPanel);
            app.ReconTimeCorrectedLabel.Text = 'Reconstruction Time = x seconds';
            app.ReconTimeLabel = uilabel(app.PinvReconPanel);
            app.ReconTimeLabel.Text = 'Reconstruction Time = x seconds';
            app.GetPinvReconButton = uibutton(app.UIFigure, 'push');
            app.GetPinvReconButton.ButtonPushedFcn = createCallbackFcn(app, @GetPinvReconButtonPushed, true);
            app.GetPinvReconButton.Text = 'Get Pinv-Recon';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ExportDataButton = uibutton(app.UIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PinvReconApp_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end