classdef PinvSimMainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClearAllButton               matlab.ui.control.Button
        ExportResultsButton          matlab.ui.control.Button
        SRFandNoiseMapsPanel         matlab.ui.container.Panel
        PinvReconSimulatorAppLabel   matlab.ui.control.Label
        ReconstructionPanel          matlab.ui.container.Panel
        PinvMethodDropDown           matlab.ui.control.DropDown
        PinvMethodDropDownLabel      matlab.ui.control.Label
        StartPinvReconButton         matlab.ui.control.Button
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        GeneratePhantomDataPanel     matlab.ui.container.Panel
        GeneratePhantomButton        matlab.ui.control.Button
        ClearButton_2                matlab.ui.control.Button
        LoadSuccessfulCheckBox_2     matlab.ui.control.CheckBox
        UIAxes3                      matlab.ui.control.UIAxes
        ChooseEncodeMechanismsPanel  matlab.ui.container.Panel
        RotationCheckBox             matlab.ui.control.CheckBox
        TranslationsCheckBox         matlab.ui.control.CheckBox
        ChemicalShiftCheckBox        matlab.ui.control.CheckBox
        OffResonanceB0CheckBox       matlab.ui.control.CheckBox
        CoilSensitivityCheckBox      matlab.ui.control.CheckBox
        GradientCheckBox             matlab.ui.control.CheckBox
        LoadTrajecotryPanel          matlab.ui.container.Panel
        ClearButton                  matlab.ui.control.Button
        orLabel                      matlab.ui.control.Label
        GenerateaTrajectoryButton    matlab.ui.control.Button
        LoadSuccessfulCheckBox       matlab.ui.control.CheckBox
        ImportaTrajectoryButton      matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % k-space trajectory
        Kx 
        Ky
        time

        % imaging phantom
        imaging_phantom
        npx
        Shift
        RotAngle
        
    end
    
    properties (Access = private)
        GenerateTrajectory
        CreatePhantom

        % initialise encode matrices for all effects:
        % set to empty arrays in startup

        gradient_encode
        offCtr_encode
        b0_encode
        gradNonLin_encode
        coilSense_encode
        chemShift_encode
        rot_encode
        spatioTemporal_encode

    end
    
    methods (Access = public)
        
        function update_trajectory(app, kx, ky) %t
            app.Kx = kx;
            app.Ky = ky;
            % app.time = t;
            plot(app.UIAxes2, app.Kx, app.Ky)
            app.LoadSuccessfulCheckBox.Value = 1;
        end

        function update_phantom(app, P, ntx) %shift, theta
            app.imaging_phantom = P;
            app.npx = ntx;
            %app.Shift = 
            %app.RotAngle = 
            imagesc(app.UIAxes3, app.imaging_phantom)
            app.LoadSuccessfulCheckBox_2.Value = 1;
            app.GeneratePhantomButton.Enable = 'on';
            app.ClearButton_2.Enable = 'on';
            app.GradientCheckBox.Value = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %add modules to path:
            % addpath(fullfile(fileparts(mfilename('fullpath')), 'modules'));
            % addpath(fullfile(fileparts(mfilename('fullpath')), 'Supporting Scripts'));
            % addpath(fullfile)

            %% Get the full path of the running app file
            appFolder = fileparts(mfilename('fullpath'));
            fprintf('app folder', appFolder)
       
            % Set MATLAB's current folder (optional)
            cd(appFolder);  
            
            % Add the app folder to the path
            addpath(appFolder);
            
            % Optionally add subfolders too
            addpath(genpath(appFolder));

            
            %set all encode matrices to empty array:
            app.gradient_encode = [];
            app.offCtr_encode = [];
            app.b0_encode = [];
            app.gradNonLin_encode = [];
            app.coilSense_encode = [];
            app.chemShift_encode = [];
            app.rot_encode = [];
            app.spatioTemporal_encode = [];

            %Gradient Encode must always be included
            app.GradientCheckBox.Value = 0;
            app.GradientCheckBox.Enable = 'off';
            app.LoadSuccessfulCheckBox.Enable = 0;
            app.LoadSuccessfulCheckBox_2.Enable = 0;
            
            %Disable clear buttons until there is data to clear
            app.ClearButton.Enable = 'off';
            app.ClearButton_2.Enable = 'off';
            app.ClearAllButton.Enable = 'off';
        end

        % Button pushed function: ImportaTrajectoryButton
        function ImportaTrajectoryButtonPushed(app, event)
            %import must be a .mat file 
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');    
            if isequal(file, 0)
                return; % User canceled
            end
        
            try
                data = load(fullfile(path, file));
                vars = fieldnames(data);
        
                if length(vars) ~= 3
                    errordlg('The file must contain exactly 3 variables, corresponding to Kx, Ky and time.', 'Import Error');
                    return;
                end
        
                vectors = {};
                for i = 1:length(vars)
                    val = data.(vars{i});
                    if isnumeric(val) && isvector(val)
                        vectors{end+1} = val; %#ok<AGROW>
                    else
                        errordlg(['Variable "' vars{i} '" is not a numeric vector.'], 'Import Error');
                        return;
                    end
                end
        
                % Assign to app properties 
                app.Kx = vectors{1};
                app.Ky = vectors{2};
                app.time = vectors{3};
        
                msgbox('Trajectory imported successfully.', 'Import Successful');
        
            catch ME
                errordlg(['Failed to load file: ' ME.message], 'Load Error');
            end
            
            %plot imported trajectory
            plot(app.UIAxes2, app.Kx, app.Ky)
            app.LoadSuccessfulCheckBox.Value = 1;

            %disable/enable button after dialog box is opened
            app.ClearButton.Enable = 'on';
            app.GenerateaTrajectoryButton.Enable = 'off';
            app.ImportaTrajectoryButton.Enable = 'off';

        end

        % Button pushed function: GenerateaTrajectoryButton
        function GenerateaTrajectoryButtonPushed(app, event)
            app.GenerateTrajectory = GenerateTrajectoryApp(app);

            %disable/enable button after dialog box is opened
            app.ClearButton.Enable = 'on';
            app.GenerateaTrajectoryButton.Enable = 'off';
            app.ImportaTrajectoryButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.GenerateTrajectory)
            delete(app.CreatePhantom)
            delete(app)
        end

        % Callback function
        function CoilSensitivityCheckBoxValueChanged(app, event)
            value = app.CoilSensitivityCheckBox.Value;
            if value == 1
                %add option to import coil sensitivity map

            elseif value == 0
                app.coilSense_encode.Value = [];
                
            end
            
        end

        % Value changed function: OffResonanceB0CheckBox
        function OffResonanceB0CheckBoxValueChanged(app, event)
            value = app.OffResonanceB0CheckBox.Value;
            if value == 1
                %add option to import coil sensitivity map

            elseif value == 0
                app.coilSense_encode.Value = [];
                
            end
        end

        % Value changed function: ChemicalShiftCheckBox
        function ChemicalShiftCheckBoxValueChanged(app, event)
            value = app.ChemicalShiftCheckBox.Value;
            if value == 1
                %add option to import map

            elseif value == 0
                app.chemShift_encode.Value = [];
                
            end
            
        end

        % Value changed function: TranslationsCheckBox
        function TranslationsCheckBoxValueChanged(app, event)
            value = app.TranslationsCheckBox.Value;
             if value == 1
                %add option to import map

            elseif value == 0
                app.offCtr_encode.Value = [];
                
            end
        end

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            app.CreatePhantom = CreatePhantomApp(app);

            app.GeneratePhantomButton.Enable = 'off';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.LoadSuccessfulCheckBox.Value = 0;
            app.GenerateaTrajectoryButton.Enable = 'on';
            app.ImportaTrajectoryButton.Enable = 'on';
            cla(app.UIAxes2)
            app.Kx = [];
            app.Ky = [];
        end

        % Button pushed function: ClearButton_2
        function ClearButton_2Pushed(app, event)
            app.GeneratePhantomButton.Enable = 'on';
            app.LoadSuccessfulCheckBox_2.Value = 0;
            
            cla(app.UIAxes3);

            app.imaging_phantom = [];
            app.npx = [];
            app.Shift =[];
            app.RotAngle = [];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadTrajecotryPanel = uipanel(app.UIFigure);
            app.LoadTrajecotryPanel.Title = '1. Load Trajecotry';
            app.UIAxes2 = uiaxes(app.LoadTrajecotryPanel);
            title(app.UIAxes2, 'k-space trajectory')
            xlabel(app.UIAxes2, 'kx')
            ylabel(app.UIAxes2, 'ky')
            zlabel(app.UIAxes2, 'kz')
            app.ImportaTrajectoryButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.ImportaTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ImportaTrajectoryButtonPushed, true);
            app.ImportaTrajectoryButton.Text = 'Import a Trajectory';
            app.LoadSuccessfulCheckBox = uicheckbox(app.LoadTrajecotryPanel);
            app.LoadSuccessfulCheckBox.Text = 'Load Successful';
            app.GenerateaTrajectoryButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.GenerateaTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateaTrajectoryButtonPushed, true);
            app.GenerateaTrajectoryButton.Text = 'Generate a Trajectory';
            app.orLabel = uilabel(app.LoadTrajecotryPanel);
            app.orLabel.Text = 'or';
            app.ClearButton = uibutton(app.LoadTrajecotryPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ChooseEncodeMechanismsPanel = uipanel(app.UIFigure);
            app.ChooseEncodeMechanismsPanel.Title = '3. Choose Encode Mechanisms';
            app.GradientCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.GradientCheckBox.Text = 'Gradient';
            app.CoilSensitivityCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.CoilSensitivityCheckBox.Text = 'Coil Sensitivity';
            app.OffResonanceB0CheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.OffResonanceB0CheckBox.ValueChangedFcn = createCallbackFcn(app, @OffResonanceB0CheckBoxValueChanged, true);
            app.OffResonanceB0CheckBox.Text = 'Off Resonance (B0)';
            app.ChemicalShiftCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.ChemicalShiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChemicalShiftCheckBoxValueChanged, true);
            app.ChemicalShiftCheckBox.Text = 'Chemical Shift';
            app.TranslationsCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.TranslationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @TranslationsCheckBoxValueChanged, true);
            app.TranslationsCheckBox.Text = 'Translations';
            app.RotationCheckBox = uicheckbox(app.ChooseEncodeMechanismsPanel);
            app.RotationCheckBox.Text = 'Rotation';
            app.GeneratePhantomDataPanel = uipanel(app.UIFigure);
            app.GeneratePhantomDataPanel.Title = '2. Generate Phantom Data';
            app.UIAxes3 = uiaxes(app.GeneratePhantomDataPanel);
            title(app.UIAxes3, 'Imaging Phantom')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            app.UIAxes3.ZLimitMethod = 'tight';
            colormap(app.UIAxes3, 'gray')
            app.LoadSuccessfulCheckBox_2 = uicheckbox(app.GeneratePhantomDataPanel);
            app.LoadSuccessfulCheckBox_2.Text = 'Load Successful';
            app.ClearButton_2 = uibutton(app.GeneratePhantomDataPanel, 'push');
            app.ClearButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearButton_2Pushed, true);
            app.ClearButton_2.Text = 'Clear';
            app.GeneratePhantomButton = uibutton(app.GeneratePhantomDataPanel, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.ReconstructionPanel = uipanel(app.UIFigure);
            app.ReconstructionPanel.Title = '4. Reconstruction';
            app.UIAxes = uiaxes(app.ReconstructionPanel);
            title(app.UIAxes, 'Gradient Only Recon')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            colormap(app.UIAxes, 'gray')
            app.UIAxes_2 = uiaxes(app.ReconstructionPanel);
            title(app.UIAxes_2, 'Complete Recon')
            app.UIAxes_2.XLimitMethod = 'tight';
            app.UIAxes_2.YLimitMethod = 'tight';
            app.UIAxes_2.ZLimitMethod = 'tight';
            colormap(app.UIAxes_2, 'gray')
            app.StartPinvReconButton = uibutton(app.ReconstructionPanel, 'push');
            app.StartPinvReconButton.Text = 'Start Pinv-Recon';
            app.PinvMethodDropDownLabel = uilabel(app.ReconstructionPanel);
            app.PinvMethodDropDownLabel.Text = 'Pinv Method';
            app.PinvMethodDropDown = uidropdown(app.ReconstructionPanel);
            app.PinvMethodDropDown.Items = {'Cholesky', 'SVD'};
            app.PinvMethodDropDown.Value = 'Cholesky';
            app.PinvReconSimulatorAppLabel = uilabel(app.UIFigure);
            app.PinvReconSimulatorAppLabel.Text = 'Pinv-Recon Simulator App';
            app.SRFandNoiseMapsPanel = uipanel(app.UIFigure);
            app.SRFandNoiseMapsPanel.Title = '5. SRF and Noise Maps';
            app.ExportResultsButton = uibutton(app.UIFigure, 'push');
            app.ExportResultsButton.Text = 'Export Results';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.Text = 'Clear All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PinvSimMainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end