classdef app_for_b0_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PinvReconwithB0FieldCorrectionUIFigure  matlab.ui.Figure
        UploadInputDataLabel        matlab.ui.control.Label
        ExecutionTimeinsecondsEditField_2  matlab.ui.control.NumericEditField
        ExecutionTimeinsecondsEditField_2Label  matlab.ui.control.Label
        ResultsLabel                matlab.ui.control.Label
        DisplayInputDataLabel       matlab.ui.control.Label
        GetReconstructionsButton    matlab.ui.control.Button
        SaveResultsButton           matlab.ui.control.Button
        CurrentDataFileEditField_2  matlab.ui.control.EditField
        CurrentDataFileEditField_2Label  matlab.ui.control.Label
        Displayb0fieldmapCheckBox   matlab.ui.control.CheckBox
        DisplaykspacetrajectoryCheckBox  matlab.ui.control.CheckBox
        LoadDataandWaveformButton   matlab.ui.control.Button
        CurrentDataFileEditField    matlab.ui.control.EditField
        CurrentDataFileLabel        matlab.ui.control.Label
        LoadB0filedmapButton        matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %initialise empty arrays (or empty objects?)
        FieldMap = [];
        FieldMap_FileName = [];

        PhantomandWaveform = [];
        PhantomandWaveform_FileName = [];

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.UIAxes2.Visible = 'off';
            app.UIAxes3.Visible = 'off';
            app.UIAxes4.Visible = 'off';
            app.CurrentDataFileEditField.Visible = 'off';
            app.CurrentDataFileEditField_2.Visible = 'off';
            app.ExecutionTimeinsecondsEditField_2.Visible = 'off';
            
            % Get folder Pinv-Recon_Dev and add all subfolders to path:
            currentFolder = fileparts(mfilename('fullpath'));
            targetFolder = 'Pinv-Recon_Dev';

            while true
                [parentFolder, currentName] = fileparts(currentFolder);
        
                if strcmp(currentName, targetFolder)
                    % Found
                    rootDirectory = currentFolder;
                    % Add Pinv-Recon_Dev and subfolders to the path
                    addpath(genpath(rootDirectory));
                    break;
                elseif isempty(parentFolder) || strcmp(currentFolder, parentFolder)
                    % Not found
                    warning('Folder "%s" not found in parent hierarchy.', targetFolderName);
                    return;
                else
                    % Move one level up
                    currentFolder = parentFolder;
                end
            end
                       
      
            % Tool Tips:
            app.LoadB0filedmapButton.Tooltip = 'For example, choose ''fieldmap.mat'' from inside the ''data'' folder!' ;
            app.LoadDataandWaveformButton.Tooltip = 'For example, choose ''structural_phantom_mtx48.mat'' from inside the ''data'' folder!' ;
            
            % display welcome message:
            uiwait(msgbox("Welcome to the B0 inhomogeneity MRI pinv recon app!","Welcome","modal"));

        end

        % Button pushed function: LoadDataandWaveformButton
        function LoadDataandWaveformButtonPushed(app, event)
            %% Load Phantom and Waveform data  
            
            %Note: change the file type filter later ??

            [file, path] = uigetfile({'*.mat;*.csv;*.txt', 'Data Files (*.mat, *.csv, *.txt)'}, ...
                                     'Select a Data File');
            
            app.PhantomandWaveform_FileName = file;
            app.PhantomandWaveform_FileName = app.PhantomandWaveform_FileName(1:end-4);

            if isequal(file, 0)
                uialert(app.PinvReconwithB0FieldCorrectionUIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        data = load(fullPath);
                    case '.csv'
                        data = readtable(fullPath);
                    case '.txt'
                        data = readtable(fullPath, 'Delimiter', '\t');
                    otherwise
                        uialert(app.PinvReconwithB0FieldCorrectionUIFigure, 'Unsupported file type.', 'Error');
                        return;
                end

                app.PhantomandWaveform = data;
                % disp('phantom and waveform file is:')
                % disp(app.PhantomandWaveform)
                

                app.CurrentDataFileEditField_2.Visible = 'on';
                app.CurrentDataFileEditField_2.Value = fullPath;  

                
            end
        end

        % Button pushed function: LoadB0filedmapButton
        function LoadB0filedmapButtonPushed(app, event)
           
            %Note: change the file type filter later ??

            [file, path] = uigetfile({'*.mat;*.csv;*.txt', 'Data Files (*.mat, *.csv, *.txt)'}, ...
                                     'Select a Data File');
            
            app.FieldMap_FileName = file;
            app.FieldMap_FileName = app.FieldMap_FileName(1:end-4);

            if isequal(file, 0)
                uialert(app.PinvReconwithB0FieldCorrectionUIFigure, 'No file selected.', 'File Load Cancelled');
            else
                fullPath = fullfile(path, file);
                [~, ~, ext] = fileparts(fullPath);
                
                switch ext
                    case '.mat'
                        data = load(fullPath);
                    case '.csv'
                        data = readtable(fullPath);
                    case '.txt'
                        data = readtable(fullPath, 'Delimiter', '\t');
                    otherwise
                        uialert(app.PinvReconwithB0FieldCorrectionUIFigure, 'Unsupported file type.', 'Error');
                        return;
                end
                
                app.FieldMap = data;
       %         fieldmap = app.FieldMap_FileName;
        %        data = app.FieldMap.(fieldmap);
          %      app.FieldMap.(fieldmap) = imresize(data, 48); % RESCALE B0 MAP
                                           % so it can be used with the
                                           % data set structural_phantom_mtx48.mat
                                           % to run it faster
                                           % change this later!!!!


                app.CurrentDataFileEditField.Visible = 'on';
                app.CurrentDataFileEditField.Value = fullPath;  
                Displayb0fieldmapCheckBoxValueChanged(app, event)
                
            end

        end

        % Value changed function: Displayb0fieldmapCheckBox
        function Displayb0fieldmapCheckBoxValueChanged(app, event)
           
            value = app.Displayb0fieldmapCheckBox.Value;
           
                
            if  size(app.FieldMap,1)==0 && value==1
                uiwait(msgbox('No B_0 field map data has been uploaded!'))
                app.Displayb0fieldmapCheckBox.Value = 0;
            elseif value ==1
                app.UIAxes.Visible = 'on';
                fieldmap = app.FieldMap_FileName;
                map = app.FieldMap.(fieldmap);
                imagesc(app.UIAxes, flipud(map)), title(app.UIAxes, 'B_0 field map');
            else 
                cla(app.UIAxes);  
               % title(app.UIAxes, 'Display input data');
                app.UIAxes.Visible = 'off';

            end

        %    x_dim = size(app.FieldMap.(fieldmap), 1);

          %  y_dim = size(app.FieldMap(fieldmap), 2);

  %          fieldmap = app.FieldMap_FileName;
         %   map = app.FieldMap.(fieldmap);
           
              %    x_dim = size(app.FieldMap.(fieldmap), 1);

          %  y_dim = size(app.FieldMap(fieldmap), 2);
            
      %      if value ==1
         %      imagesc(app.UIAxes, map), title(app.UIAxes, '"B_0 field map"');
       %     else 
    %            cla(app.UIAxes);  
   %             title(app.UIAxes, 'Display input data');
  %          end 
            
            
        end

        % Value changed function: DisplaykspacetrajectoryCheckBox
        function DisplaykspacetrajectoryCheckBoxValueChanged(app, event)
            value = app.DisplaykspacetrajectoryCheckBox.Value;
           
                
            if  size(app.PhantomandWaveform,1)==0 && value==1
                uiwait(msgbox('No waveform data has been uploaded!'))
                app.DisplaykspacetrajectoryCheckBox.Value = 0;
            elseif value ==1
                app.UIAxes4.Visible = 'on';

                k = app.PhantomandWaveform.wf.k;
  
                plot(app.UIAxes4,real(k), imag(k)), title(app.UIAxes4, 'k-space trajectory');
            else 
                cla(app.UIAxes4);  
                app.UIAxes4.Visible = 'off';

            end
        end

        % Button pushed function: GetReconstructionsButton
        function GetReconstructionsButtonPushed(app, event)
            tic;  %start timer

            %progress dialog
            d = uiprogressdlg(app.PinvReconwithB0FieldCorrectionUIFigure, ...
            'Title','Please wait...', ...
            'Message','Processing...', ...
            'Indeterminate','on');
            
            %This uses Kylies example code:
            %C:\Users\sofia\OneDrive\Desktop\\Pinv-Recon_Dev\misc\public\Examples\structural_phantom_b0_eg.m


            %check data is correctly uploaded; 
            if size(app.FieldMap,1)==0
                 close(d); %close dialog
                 uiwait(msgbox('No B_0 field map data has been uploaded!'))

            elseif size(app.PhantomandWaveform,1) == 0
                 close(d); %close dialog
                 uiwait(msgbox('No phantom and waveform data has been uploaded!'))

            else
                wf = app.PhantomandWaveform.wf;
                data = app.PhantomandWaveform.data;
                fieldmap_name = app.FieldMap_FileName;
                fieldmap = app.FieldMap.(fieldmap_name);
                %~~~~~~~~~~~~~~~~~
                %Kylies b0 code: (with some additions)
                disp(app.PhantomandWaveform_FileName)

                mtx_acq= wf.npix;
                nslices=1;
                ntimesteps=1;
                ncoils=size(data,1);
                    
                mtx_reco=mtx_acq;
                % mtx_reco=2*mtx_acq; % to overdiscretize recon                

                % [image,imageabs,Recon]=pinv_recon_public(data,wf,[],mtx_reco);
                % [image_b0,imageabs_b0,Recon_b0]=pinv_recon_public(data,wf,[],mtx_reco,fieldmap);
                [~,imageabs]=pinv_recon_public(data,wf,[],mtx_reco);
                [~,imageabs_b0]=pinv_recon_public(data,wf,[],mtx_reco,fieldmap);

                %~~~~~~~~~~~~~~~~~

                %plot results:
                
                imagesc(app.UIAxes2, flipud(imageabs)), title("Pinv");
                imagesc(app.UIAxes3,flipud(imageabs_b0)), title("Pinv + OffRes");


                app.UIAxes2.Visible = 'on';
                app.UIAxes3.Visible = 'on';

                close(d); %close dialog    
                elapsedTime = toc;  % stop timer
                
                app.ExecutionTimeinsecondsEditField_2.Visible = 'on';
                app.ExecutionTimeinsecondsEditField_2.Value = elapsedTime;

                % msg = msgbox(sprintf('Function executed in %.2f seconds.', elapsedTime));
                % drawnow;
                % pause(2); %wait 2 seconds
                % 
                % if isvalid(msg)
                %     close(msg);
                % end
            end


            % value = app.npixelsEditField.Value;
            % if mod(value,1) ~= 0
            % 
            %     close(d); %close dialog
            %     uiwait(msgbox('n pixels must be an integer!'))
            % 
            % elseif value<1
            % 
            %     close(d); %close dialog
            %     uiwait(msgbox('n pixels must be greater than 0!'))
            % 
            % else
            %     P = phantom('Modified Shepp-Logan', value);
            %     imagesc(app.UIAxes,P);
            %     app.UIAxes.YLim = [0 value];
            %     app.UIAxes.XLim = [0 value];
            % 
            %     close(d); %close dialog    
            %     elapsedTime = toc;  % stop timer
            % 
            %     msg = msgbox(sprintf('Function executed in %.2f seconds.', elapsedTime));
            %     drawnow;
            %     pause(2); %wait 2 seconds
            % 
            %     if isvalid(msg)
            %         close(msg);
            %     end
            % end     
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            
            [filename1, pathname1] = uiputfile({'*.png'}, 'Save Pinv Plot As');
            if ischar(filename1)
                exportgraphics(app.UIAxes2, fullfile(pathname1, filename1));
            end
                
            [filename2, pathname2] = uiputfile({'*.png'}, 'Save Pinv+OffRes Plot As');
            if ischar(filename2)
                exportgraphics(app.UIAxes3, fullfile(pathname2, filename2));
            end

            %add verification to see if plots got saved??
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PinvReconwithB0FieldCorrectionUIFigure = uifigure('Visible', 'off');
            app.PinvReconwithB0FieldCorrectionUIFigure.Name = 'Pinv Recon with B0 Field Correction';
            app.UIAxes = uiaxes(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            colormap(app.UIAxes, 'gray')
            app.UIAxes2 = uiaxes(app.PinvReconwithB0FieldCorrectionUIFigure);
            title(app.UIAxes2, 'Pinv')
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            colormap(app.UIAxes2, 'gray')
            app.UIAxes3 = uiaxes(app.PinvReconwithB0FieldCorrectionUIFigure);
            title(app.UIAxes3, 'Pinv+OffRes')
            app.UIAxes3.XLimitMethod = 'tight';
            app.UIAxes3.YLimitMethod = 'tight';
            colormap(app.UIAxes3, 'gray')
            app.UIAxes4 = uiaxes(app.PinvReconwithB0FieldCorrectionUIFigure);
            xlabel(app.UIAxes4, 'kx')
            ylabel(app.UIAxes4, 'ky')
            app.LoadB0filedmapButton = uibutton(app.PinvReconwithB0FieldCorrectionUIFigure, 'push');
            app.LoadB0filedmapButton.ButtonPushedFcn = createCallbackFcn(app, @LoadB0filedmapButtonPushed, true);
            app.LoadB0filedmapButton.Text = 'Load B0 filed map';
            app.CurrentDataFileLabel = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.CurrentDataFileLabel.Text = 'Current Data File:';
            app.CurrentDataFileEditField = uieditfield(app.PinvReconwithB0FieldCorrectionUIFigure, 'text');
            app.LoadDataandWaveformButton = uibutton(app.PinvReconwithB0FieldCorrectionUIFigure, 'push');
            app.LoadDataandWaveformButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataandWaveformButtonPushed, true);
            app.LoadDataandWaveformButton.Text = 'Load Data and Waveform';
            app.DisplaykspacetrajectoryCheckBox = uicheckbox(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.DisplaykspacetrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplaykspacetrajectoryCheckBoxValueChanged, true);
            app.DisplaykspacetrajectoryCheckBox.Text = 'Display k-space trajectory';
            app.Displayb0fieldmapCheckBox = uicheckbox(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.Displayb0fieldmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @Displayb0fieldmapCheckBoxValueChanged, true);
            app.Displayb0fieldmapCheckBox.Text = 'Display b0 fieldmap';
            app.CurrentDataFileEditField_2Label = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.CurrentDataFileEditField_2Label.Text = 'Current Data File:';
            app.CurrentDataFileEditField_2 = uieditfield(app.PinvReconwithB0FieldCorrectionUIFigure, 'text');
            app.SaveResultsButton = uibutton(app.PinvReconwithB0FieldCorrectionUIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.GetReconstructionsButton = uibutton(app.PinvReconwithB0FieldCorrectionUIFigure, 'push');
            app.GetReconstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @GetReconstructionsButtonPushed, true);
            app.GetReconstructionsButton.Text = 'Get Reconstructions';
            app.DisplayInputDataLabel = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.DisplayInputDataLabel.Text = '2. Display Input Data:';
            app.ResultsLabel = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.ResultsLabel.Text = '3. Results:';
            app.ExecutionTimeinsecondsEditField_2Label = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.ExecutionTimeinsecondsEditField_2Label.Text = 'Execution Time (in seconds): ';
            app.ExecutionTimeinsecondsEditField_2 = uieditfield(app.PinvReconwithB0FieldCorrectionUIFigure, 'numeric');
            app.UploadInputDataLabel = uilabel(app.PinvReconwithB0FieldCorrectionUIFigure);
            app.UploadInputDataLabel.Text = '1. Upload Input Data:';
            % Show the figure after all components are created
            app.PinvReconwithB0FieldCorrectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_for_b0_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PinvReconwithB0FieldCorrectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PinvReconwithB0FieldCorrectionUIFigure)
        end
    end
end