classdef CreatePhantomApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DoyouwanttousethisphantominthesimulationLabel  matlab.ui.control.Label
        ConfirmButton                  matlab.ui.control.Button
        CreateImagingPahntomPanel      matlab.ui.container.Panel
        ShiftEditField                 matlab.ui.control.NumericEditField
        ShiftEditFieldLabel            matlab.ui.control.Label
        DegreesLabel                   matlab.ui.control.Label
        RotationAngleEditField         matlab.ui.control.NumericEditField
        RotationAngleEditFieldLabel    matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        GeneratePhantomButton          matlab.ui.control.Button
        numberofslicesEditField        matlab.ui.control.NumericEditField
        numberofslicesEditFieldLabel   matlab.ui.control.Label
        npixEditField                  matlab.ui.control.NumericEditField
        npixEditFieldLabel             matlab.ui.control.Label
        PhantomDimanesionsSwitch       matlab.ui.control.Switch
        PhantomDimanesionsSwitchLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp; % Description
        imaging_phantom
        npx
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
        %    addpath(fullfile(fileparts(fileparts(mfilename('fullpath')), 'Supporting Scripts')));
            
            % Store main app in property
            app.MainApp = main_app;
            
            app.PhantomDimanesionsSwitch.Value = "2D";
            app.numberofslicesEditField.Visible = 'off';
            app.numberofslicesEditFieldLabel.Visible = 'off';
        end

        % Value changed function: PhantomDimanesionsSwitch
        function PhantomDimanesionsSwitchValueChanged(app, event)
            dimension = app.PhantomDimanesionsSwitch.Value;  

            if dimension == "2D"

                app.numberofslicesEditField.Visible = 'off';
                app.numberofslicesEditFieldLabel.Visible = 'off';
          

            elseif dimension == "3D"
                app.numberofslicesEditField.Visible = 'on';
                app.numberofslicesEditFieldLabel.Visible = 'on';
            end

        end

        % Button pushed function: GeneratePhantomButton
        function GeneratePhantomButtonPushed(app, event)
            
            npixEditFieldValueChanged(app,event); %checks the value is okay
            npix = app.npixEditField.Value;
            app.npx = npix;
            
            dimension = app.PhantomDimanesionsSwitch.Value;

            if dimension == "2D"

                P = phantom('Modified Shepp-Logan', npix);
                app.imaging_phantom = P;

                imagesc(app.UIAxes,P);
                % app.UIAxes.YLim = [0 npix];
                % app.UIAxes.XLim = [0 npix];
              
            elseif dimension == "3D"
                
                P = phantom3d('Modified Shepp-Logan', npix);
                app.imaging_phantom = P;
                nslices = app.numberofslicesEditField.Value;

                chosen_slices = zeros(nslices, 1);
                for n = 1:nslices
                    slice = round((n/(nslices+1))*npix);
                    chosen_slices(n,1) = slice;
                end
                
                P = P(:,:,chosen_slices); 

                %% GET THE PLOTTING TO WORK
                app.mat2montage(P), %title('Chosen Slices');
                % imagesc(app.UIAxes,P);
                % app.UIAxes.YLim = [0 npix];
                % app.UIAxes.XLim = [0 npix];

            end

           
        end

        % Value changed function: npixEditField
        function npixEditFieldValueChanged(app, event)
            npix = app.npixEditField.Value;
            if mod(npix,1) ~= 0
                
                close(d); %close dialog
                uiwait(msgbox('n pixels must be an integer!'))

            elseif npix<1
               
                close(d); %close dialog
                uiwait(msgbox('n pixels must be greater than 0!'))
                
            end     
        end

        % Value changed function: numberofslicesEditField
        function numberofslicesEditFieldValueChanged(app, event)
            nslices = app.numberofslicesEditField.Value;
            npix = app.npixEditField.Value;

            if mod(nslices,1) ~= 0
                
                close(d); %close dialog
                uiwait(msgbox('Number of slices must be an integer!'))

            elseif nslices<1
               
                close(d); %close dialog
                uiwait(msgbox('Number of slices must be greater than 0.'))
            elseif nslices>npix

                
                close(d); %close dialog
                uiwait(msgbox('Number of slices must be less than number of phantom pixels.'))
            end    
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %export vars to workspace
             choice = questdlg('What would you like to export?', ...
                              'Export Options', ...
                              'Export Variables', ...
                              'Export Image', ...
                              'Cancel');
        
            % Handle user choice
            switch choice
                case 'Export Variables'
                    
                    assignin('base', 'npix', app.npx);
                    assignin('base', 'phantom', app.imaging_phantom);

                    msgbox('Variables exported to workspace successfully.', 'Export Success');
        
                case 'Export Plot'
                    [file, path] = uiputfile({'*.png';'*.pdf'}, 'Save As');
                    if ischar(file)
                        exportgraphics(app.UIAxes, fullfile(path, file));
                        msgbox('Phantom image exported successfully.', 'Export Success');
                    end
        
                otherwise
                    % Do nothing (Cancel)
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            update_phantom(app.MainApp, app.imaging_phantom, app.npx)
            
            %Re-enable button:
            app.MainApp.GeneratePhantomButton.Enable = 'on';

            %Close app
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imaging Phantom')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            colormap(app.UIAxes, 'gray')
            app.CreateImagingPahntomPanel = uipanel(app.UIFigure);
            app.CreateImagingPahntomPanel.Title = 'Create Imaging Pahntom';
            app.PhantomDimanesionsSwitchLabel = uilabel(app.CreateImagingPahntomPanel);
            app.PhantomDimanesionsSwitchLabel.Text = 'Phantom Dimanesions';
            app.PhantomDimanesionsSwitch = uiswitch(app.CreateImagingPahntomPanel, 'slider');
            app.PhantomDimanesionsSwitch.Items = {'2D', '3D'};
            app.PhantomDimanesionsSwitch.ValueChangedFcn = createCallbackFcn(app, @PhantomDimanesionsSwitchValueChanged, true);
            app.PhantomDimanesionsSwitch.Value = '2D';
            app.npixEditFieldLabel = uilabel(app.CreateImagingPahntomPanel);
            app.npixEditFieldLabel.Text = 'npix';
            app.npixEditField = uieditfield(app.CreateImagingPahntomPanel, 'numeric');
            app.npixEditField.Limits = [1 Inf];
            app.npixEditField.ValueChangedFcn = createCallbackFcn(app, @npixEditFieldValueChanged, true);
            app.npixEditField.Value = 64;
            app.numberofslicesEditFieldLabel = uilabel(app.CreateImagingPahntomPanel);
            app.numberofslicesEditFieldLabel.Text = 'number of slices';
            app.numberofslicesEditField = uieditfield(app.CreateImagingPahntomPanel, 'numeric');
            app.numberofslicesEditField.Limits = [1 Inf];
            app.numberofslicesEditField.ValueChangedFcn = createCallbackFcn(app, @numberofslicesEditFieldValueChanged, true);
            app.numberofslicesEditField.Value = 1;
            app.GeneratePhantomButton = uibutton(app.CreateImagingPahntomPanel, 'push');
            app.GeneratePhantomButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePhantomButtonPushed, true);
            app.GeneratePhantomButton.Text = 'Generate Phantom';
            app.ExportButton = uibutton(app.CreateImagingPahntomPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.RotationAngleEditFieldLabel = uilabel(app.CreateImagingPahntomPanel);
            app.RotationAngleEditFieldLabel.Text = 'Rotation Angle';
            app.RotationAngleEditField = uieditfield(app.CreateImagingPahntomPanel, 'numeric');
            app.DegreesLabel = uilabel(app.CreateImagingPahntomPanel);
            app.DegreesLabel.Text = 'Degrees';
            app.ShiftEditFieldLabel = uilabel(app.CreateImagingPahntomPanel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.CreateImagingPahntomPanel, 'numeric');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.DoyouwanttousethisphantominthesimulationLabel = uilabel(app.UIFigure);
            app.DoyouwanttousethisphantominthesimulationLabel.Text = 'Do you want to use this phantom in the simulation?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreatePhantomApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end