classdef GenerateTrajectoryApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OKButton                  matlab.ui.control.Button
        ExportTrajectoryButton    matlab.ui.control.Button
        npixEditField             matlab.ui.control.NumericEditField
        npixEditFieldLabel        matlab.ui.control.Label
        FOVEditField              matlab.ui.control.NumericEditField
        FOVEditFieldLabel         matlab.ui.control.Label
        GenerateTrajectoryButton  matlab.ui.control.Button
        nucleusDropDown           matlab.ui.control.DropDown
        nucleusDropDownLabel      matlab.ui.control.Label
        smaxEditField             matlab.ui.control.NumericEditField
        smaxEditFieldLabel        matlab.ui.control.Label
        ksampSpinner              matlab.ui.control.Spinner
        ksampSpinnerLabel         matlab.ui.control.Label
        gmaxEditField             matlab.ui.control.NumericEditField
        gmaxEditFieldLabel        matlab.ui.control.Label
        armsEditField             matlab.ui.control.NumericEditField
        armsEditFieldLabel        matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp; % Description
        Kx;
        Ky;
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
       %     addpath(fullfile(fileparts(fileparts(mfilename('fullpath')), 'Supporting Scripts')));
           

            % Store main app in property
            app.MainApp = main_app;
        end

        % Drop down opening function: nucleusDropDown
        function nucleusDropDownOpening(app, event)
            
        end

        % Callback function
        function nucleusDropDownValueChanged(app, event)
            
        end

        % Value changed function: gmaxEditField
        function gmaxEditFieldValueChanged(app, event)
   
        end

        % Value changed function: smaxEditField
        function smaxEditFieldValueChanged(app, event)
        
        end

        % Value changed function: ksampSpinner
        function ksampSpinnerValueChanged(app, event)
            
        end

        % Button pushed function: GenerateTrajectoryButton
        function GenerateTrajectoryButtonPushed(app, event)
            
            %progress dialog
            d = uiprogressdlg(app.UIFigure, ...
            'Title','Please wait...', ...
            'Message','Processing...', ...
            'Indeterminate','on');

            % get design_spiral input vars            
            fov = round(app.FOVEditField.Value);
            app.FOVEditField.Value = fov;
            npix = round(app.npixEditField.Value);
            app.npixEditField.Value = npix;
            ksamp = app.ksampSpinner.Value;
            smax = app.smaxEditField.Value;
            gmax = app.gmaxEditField.Value;
            nucleus = app.nucleusDropDown.Value;
            arms = app.armsEditField.Value;
            
            %hard code these vars (add options to app later)            
            acq_round2n = true;
            balanced = true;

            %addd other function outputs later
            [k,dcf,t,ind,grad]=design_spiral_for_app(fov,npix,arms,ksamp,gmax,smax,nucleus,acq_round2n,balanced);
            app.Kx = real(k);
            app.Ky = imag(k);
            plot(app.UIAxes, app.Kx, app.Ky), 
           

        end

        % Value changed function: armsEditField
        function armsEditFieldValueChanged(app, event)
    
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            update_trajectory(app.MainApp, app.Kx, app.Ky)
            
            %Re-enable button:
            app.MainApp.GenerateaTrajectoryButton.Enable = 'on';

            %Close app
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if isvalid(app.MainApp)
            %     app.MainApp.GenerateaTrajectoryButton.Enable = 'on';
            % 
            % end
            
            app.MainApp.GenerateaTrajectoryButton.Enable = 'on';

            delete(app)
            
        end

        % Button pushed function: ExportTrajectoryButton
        function ExportTrajectoryButtonPushed(app, event)
            % Ask the user what to export
            choice = questdlg('What would you like to export?', ...
                              'Export Options', ...
                              'Export Variables', ...
                              'Export Plot', ...
                              'Cancel');
        
            % Handle user choice
            switch choice
                case 'Export Variables'
                    
                    assignin('base', 'fov', app.FOVEditField.Value);
                    assignin('base', 'npix', app.npixEditField.Value);
                    assignin('base', 'ksamp', app.ksampSpinner.Value);
                    assignin('base', 'smax', app.smaxEditField.Value);
                    assignin('base', 'gmax', app.gmaxEditField.Value);
                    assignin('base', 'nucleus', app.nucleusDropDown.Value);
                    assignin('base', 'arms', app.armsEditField.Value);
                    assignin('base', 'Kx', app.Kx);
                    assignin('base', 'Ky', app.Ky);

                    msgbox('Variables exported to workspace successfully.', 'Export Success');
        
                case 'Export Plot'
                    [file, path] = uiputfile({'*.png';'*.pdf'}, 'Save Plot As');
                    if ischar(file)
                        exportgraphics(app.UIAxes, fullfile(path, file));
                        msgbox('Plot exported successfully.', 'Export Success');
                    end
        
                otherwise
                    % Do nothing (Cancel)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'k-space Trajectory')
            xlabel(app.UIAxes, 'kx')
            ylabel(app.UIAxes, 'ky')
            zlabel(app.UIAxes, 'kz')
            app.armsEditFieldLabel = uilabel(app.UIFigure);
            app.armsEditFieldLabel.Text = 'arms';
            app.armsEditField = uieditfield(app.UIFigure, 'numeric');
            app.armsEditField.Limits = [1 Inf];
            app.armsEditField.RoundFractionalValues = 'on';
            app.armsEditField.ValueChangedFcn = createCallbackFcn(app, @armsEditFieldValueChanged, true);
            app.armsEditField.Value = 1;
            app.gmaxEditFieldLabel = uilabel(app.UIFigure);
            app.gmaxEditFieldLabel.Text = 'gmax';
            app.gmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.gmaxEditField.ValueChangedFcn = createCallbackFcn(app, @gmaxEditFieldValueChanged, true);
            app.gmaxEditField.Value = 33;
            app.ksampSpinnerLabel = uilabel(app.UIFigure);
            app.ksampSpinnerLabel.Text = 'ksamp';
            app.ksampSpinner = uispinner(app.UIFigure);
            app.ksampSpinner.Step = 4;
            app.ksampSpinner.Limits = [4 28];
            app.ksampSpinner.ValueChangedFcn = createCallbackFcn(app, @ksampSpinnerValueChanged, true);
            app.ksampSpinner.Value = 4;
            app.smaxEditFieldLabel = uilabel(app.UIFigure);
            app.smaxEditFieldLabel.Text = 'smax';
            app.smaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.smaxEditField.ValueChangedFcn = createCallbackFcn(app, @smaxEditFieldValueChanged, true);
            app.smaxEditField.Value = 120;
            app.nucleusDropDownLabel = uilabel(app.UIFigure);
            app.nucleusDropDownLabel.Text = 'nucleus';
            app.nucleusDropDown = uidropdown(app.UIFigure);
            app.nucleusDropDown.Items = {'1H', '13C', '131XE'};
            app.nucleusDropDown.DropDownOpeningFcn = createCallbackFcn(app, @nucleusDropDownOpening, true);
            app.nucleusDropDown.Value = '1H';
            app.GenerateTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.GenerateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrajectoryButtonPushed, true);
            app.GenerateTrajectoryButton.Text = 'Generate Trajectory';
            app.FOVEditFieldLabel = uilabel(app.UIFigure);
            app.FOVEditFieldLabel.Text = 'FOV';
            app.FOVEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOVEditField.Value = 180;
            app.npixEditFieldLabel = uilabel(app.UIFigure);
            app.npixEditFieldLabel.Text = 'npix';
            app.npixEditField = uieditfield(app.UIFigure, 'numeric');
            app.npixEditField.Limits = [1 Inf];
            app.npixEditField.Value = 64;
            app.ExportTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.ExportTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTrajectoryButtonPushed, true);
            app.ExportTrajectoryButton.Text = 'Export Trajectory';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateTrajectoryApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end